classdef analyst_app_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        GridLayout2                    matlab.ui.container.GridLayout
        IncludeerrorbarsSwitch         matlab.ui.control.Switch
        IncludeerrorbarsSwitchLabel    matlab.ui.control.Label
        MaximumdepthEditField          matlab.ui.control.NumericEditField
        MaximumdepthEditFieldLabel     matlab.ui.control.Label
        MinimumdepthEditField          matlab.ui.control.NumericEditField
        MinimumdepthEditFieldLabel     matlab.ui.control.Label
        ImporteddataTextArea           matlab.ui.control.TextArea
        ImporteddataTextAreaLabel      matlab.ui.control.Label
        ErrorSpacingEditField          matlab.ui.control.NumericEditField
        ErrorSpacingEditFieldLabel     matlab.ui.control.Label
        MaximumModulusEditField        matlab.ui.control.NumericEditField
        MaximumModulusEditFieldLabel   matlab.ui.control.Label
        MinimumModulusEditField        matlab.ui.control.NumericEditField
        MinimumModulusEditFieldLabel   matlab.ui.control.Label
        MaximumHardnessEditField       matlab.ui.control.NumericEditField
        MaximumHardnessEditFieldLabel  matlab.ui.control.Label
        MinimumHardnessEditField       matlab.ui.control.NumericEditField
        MinimumHardnessEditFieldLabel  matlab.ui.control.Label
        NumberofTestsEditField         matlab.ui.control.NumericEditField
        NumberofTestsEditFieldLabel    matlab.ui.control.Label
        PlotPhcurveonlyforsingletestButton  matlab.ui.control.Button
        SavecleaneddatasingletestButton  matlab.ui.control.Button
        PlotdatafrommultiplefilesButton  matlab.ui.control.Button
        ImportmultiplefilesButton      matlab.ui.control.Button
        NanoXPButton_3                 matlab.ui.control.StateButton
        iMicroButton_3                 matlab.ui.control.StateButton
        ImportsinglefileButton         matlab.ui.control.Button
        SelecttheindenterusedLabel     matlab.ui.control.Label
        SavecleaneddatamultipletestButton  matlab.ui.control.Button
        PlotdatafromsinglefileButton   matlab.ui.control.Button
        CONTROLPANELLabel              matlab.ui.control.Label
        francescbarberaupceduLabel     matlab.ui.control.Label
        FrancescBarberFlichiLabel      matlab.ui.control.Label
        PlotsLabel                     matlab.ui.control.Label
        DatacleaningLabel              matlab.ui.control.Label
        RightPanel                     matlab.ui.container.Panel
        SeeinfullscreenButton_7        matlab.ui.control.Button
        SeeinfullscreenButton_6        matlab.ui.control.Button
        SeeinfullscreenButton_5        matlab.ui.control.Button
        SeeinfullscreenButton_4        matlab.ui.control.Button
        SeeinfullscreenButton_3        matlab.ui.control.Button
        MarkerColorDropDown            matlab.ui.control.DropDown
        MarkerColorDropDownLabel       matlab.ui.control.Label
        LineMarkerStyleDropDown        matlab.ui.control.DropDown
        LineMarkerStyleDropDownLabel   matlab.ui.control.Label
        LinewidthSlider                matlab.ui.control.Slider
        LinewidthSliderLabel           matlab.ui.control.Label
        MarkerSizeSlider               matlab.ui.control.Slider
        MarkerSizeSliderLabel          matlab.ui.control.Label
        LineColorDropDown              matlab.ui.control.DropDown
        LineColorDropDownLabel         matlab.ui.control.Label
        MarkerEdgeColorDropDown        matlab.ui.control.DropDown
        MarkerEdgeColorDropDownLabel   matlab.ui.control.Label
        ErrorBarsDropDown              matlab.ui.control.DropDown
        ErrorBarsDropDownLabel         matlab.ui.control.Label
        VIEWPANELLabel                 matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        numRows_H
        numRows_M
        numRows_D_H
        numRows_D_M
        H_mean_per_row_filtered
        H_std_per_row_filtered
        M_mean_per_row_filtered
        M_std_per_row_filtered
        D_mean_per_row_H_filtered
        D_std_per_row_H_filtered
        D_mean_per_row_M_filtered
        D_std_per_row_M_filtered       
        load
        depth
        maxNumRows
        selected_file
        selected_files
        results_filtered
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: NumberofTestsEditField
        function NumberofTestsEditFieldValueChanged(app, event)
            value = app.NumberofTestsEditField.Value;            
        end

        % Value changed function: MinimumHardnessEditField
        function MinimumHardnessEditFieldValueChanged(app, event)
            value = app.MinimumHardnessEditField.Value;
        end

        % Value changed function: MaximumHardnessEditField
        function MaximumHardnessEditFieldValueChanged(app, event)
            value = app.MaximumHardnessEditField.Value;           
        end

        % Value changed function: MinimumModulusEditField
        function MinimumModulusEditFieldValueChanged(app, event)
            value = app.MinimumModulusEditField.Value;           
        end

        % Value changed function: MaximumModulusEditField
        function MaximumModulusEditFieldValueChanged(app, event)
            value = app.MaximumModulusEditField.Value;            
        end

        % Value changed function: iMicroButton_3
        function iMicroButton_3ValueChanged(app, event)
            value = app.iMicroButton_3.Value;
            app.ImportsinglefileButton.Enable = "on";
            app.ImportmultiplefilesButton.Enable = "on";

            if app.iMicroButton_3.Value == 1
                app.NanoXPButton_3.Enable = 'off';
            elseif app.iMicroButton_3.Value == 0
                app.NanoXPButton_3.Enable = 'on';
            end
        end

        % Value changed function: NanoXPButton_3
        function NanoXPButton_3ValueChanged(app, event)
            value = app.NanoXPButton_3.Value;
            app.ImportsinglefileButton.Enable = "on";
            app.ImportmultiplefilesButton.Enable = "on";

            if app.NanoXPButton_3.Value == 1
                app.iMicroButton_3.Enable = 'off';
            elseif app.NanoXPButton_3.Value == 0
                app.iMicroButton_3.Enable = 'on';
            end
        end

        % Button pushed function: ImportsinglefileButton
        function ImportsinglefileButtonPushed(app, event)
            app.PlotdatafrommultiplefilesButton.Enable = "off";
            app.PlotdatafromsinglefileButton.Enable = "on";
            app.SavecleaneddatamultipletestButton.Enable = "on";
            app.SavecleaneddatasingletestButton.Enable = "on";
            app.PlotdatafrommultiplefilesButton.Enable = "off";
            app.ErrorBarsDropDown.Enable ="off";
            app.MarkerColorDropDown.Enable = "off";
            app.LineMarkerStyleDropDown.Enable ="off";
            app.MarkerEdgeColorDropDown.Enable ="off";
            app.UIAxes4.Visible = "on";
            app.PlotPhcurveonlyforsingletestButton.Enable = "on"; 

            app.maxNumRows = 0;
            
            % Preasignación de matrices
            H_values_per_row = zeros(app.maxNumRows, app.NumberofTestsEditField.Value);
            M_values_per_row = zeros(app.maxNumRows, app.NumberofTestsEditField.Value);
            D_values_per_row_H = zeros(app.maxNumRows, app.NumberofTestsEditField.Value);
            D_values_per_row_M = zeros(app.maxNumRows, app.NumberofTestsEditField.Value);          

            h = waitbar(0, 'Importing and processing data...');
               
            if app.NumberofTestsEditField.Value == 0
                close(h);
                msgbox('Number of tests can not be 0', 'Error', 'modal')
                return;
            end

            if app.iMicroButton_3.Value == 1         
                % Selección archivo Excel
                Test_sheet = cell(1, app.NumberofTestsEditField.Value);
                [app.selected_file, path] = uigetfile('*.xlsx', 'Select an Excel file');
                
                if isequal(app.selected_file, 0)
                    close(h);
                    return; % Si se cancela la selección, vuelve a empezar
                end
                
                
                filePath = fullfile(path, app.selected_file);
                app.ImporteddataTextArea.Value = app.selected_file;

               
                for i = 1:app.NumberofTestsEditField.Value
                    sheetName = "Test " + num2str(i);   % Definición del nombre de las hojas del Excel que interesan            
                    
                    % Verificar si la hoja existe antes de intentar leerla
                    sheetNames = sheetnames(filePath);    
                    if any(strcmp(sheetNames, sheetName))
                       Test_sheet{i} = readtable(filePath, 'Sheet', sheetName, 'TextType', 'string');                                          
                    else
                       continue                                                                               
                    end 
                         
                    app.load{i} = Test_sheet{i}.LOAD;
                    app.depth{i} = Test_sheet{i}.DEPTH;

                    % Outliers values cleaning (HARDNESS and MODULUS)               
                    Condition_H = (Test_sheet{i}.HARDNESS >= app.MinimumHardnessEditField.Value & Test_sheet{i}.HARDNESS <= app.MaximumHardnessEditField.Value);
                    Condition_M = (Test_sheet{i}.MODULUS >= app.MinimumModulusEditField.Value & Test_sheet{i}.MODULUS <= app.MaximumModulusEditField.Value);
         
                    D_values_H{i} = Test_sheet{i}.DEPTH(Condition_H);                 
                    D_values_M{i} = Test_sheet{i}.DEPTH(Condition_M);                 

                    app.numRows_H = nnz(Condition_H);
                    app.numRows_M = nnz(Condition_M);
                  
                    H_values_per_row(1:app.numRows_H, i) = Test_sheet{i}.HARDNESS(Condition_H);
                    M_values_per_row(1:app.numRows_M, i) = Test_sheet{i}.MODULUS(Condition_M);
                    D_values_per_row_H(1:app.numRows_H, i) = D_values_H{i};             
                    D_values_per_row_M(1:app.numRows_M, i) = D_values_M{i}; 
                
                    app.maxNumRows = max(app.maxNumRows, max(app.numRows_H, app.numRows_M));

                     % Actualiza la barra de progreso
                    waitbar(i / app.NumberofTestsEditField.Value, h, 'Importing and processing data...');
                end
                    
                % parameters calculus
                % Calcular las estadísticas solo para los valores que cumplen con la condición
                H_mean_per_row = mean(H_values_per_row(1:app.numRows_H, :), 2);
                H_std_per_row = std(H_values_per_row(1:app.numRows_H, :), 0, 2);

                M_mean_per_row = mean(M_values_per_row(1:app.numRows_M, :), 2);
                M_std_per_row = std(M_values_per_row(1:app.numRows_M, :), 0, 2);

                D_mean_per_row_H = mean(D_values_per_row_H(1:app.numRows_H, :), 2); 
                D_std_per_row_H = std(D_values_per_row_H(1:app.numRows_H, :), 0, 2);  
                D_mean_per_row_M = mean(D_values_per_row_M(1:app.numRows_M, :), 2); 
                D_std_per_row_M = std(D_values_per_row_M(1:app.numRows_M, :), 0, 2);  
  
                % Limitar valores calculados en la media
                condition_mask_H_mean = H_mean_per_row >= app.MinimumHardnessEditField.Value & H_mean_per_row <= app.MaximumHardnessEditField.Value;
                condition_mask_M_mean = M_mean_per_row >= app.MinimumModulusEditField.Value & M_mean_per_row <= app.MaximumModulusEditField.Value;
                condition_mask_D_mean_H = D_mean_per_row_H >= app.MinimumdepthEditField.Value & D_mean_per_row_H <= app.MaximumdepthEditField.Value;
                condition_mask_D_mean_M = D_mean_per_row_M >= app.MinimumdepthEditField.Value & D_mean_per_row_M <= app.MaximumdepthEditField.Value;
                
                % Encontrar los índices comunes
                common_indices_H = intersect(find(condition_mask_H_mean), find(condition_mask_D_mean_H));
                common_indices_M = intersect(find(condition_mask_M_mean), find(condition_mask_D_mean_M));
                
                % Filtrar los valores que cumplen con la condición en H_mean_per_row y D_mean_per_row_H
                app.H_mean_per_row_filtered = H_mean_per_row(common_indices_H);
                app.H_std_per_row_filtered = H_std_per_row(common_indices_H);
                
                app.M_mean_per_row_filtered = M_mean_per_row(common_indices_M);
                app.M_std_per_row_filtered = M_std_per_row(common_indices_M);
                
                app.D_mean_per_row_H_filtered = D_mean_per_row_H(common_indices_H);
                app.D_std_per_row_H_filtered = D_std_per_row_H(common_indices_H);
                
                app.D_mean_per_row_M_filtered = D_mean_per_row_M(common_indices_M);
                app.D_std_per_row_M_filtered = D_std_per_row_M(common_indices_M);
                    
                close(h)
                msgbox('iMicro data imported successfully', 'Success', 'modal')

                elseif app.NanoXPButton_3.Value == 1                    
                    Test_sheet = cell(1, app.NumberofTestsEditField.Value);
                    [app.selected_file, path] = uigetfile('*.xls', 'Select an Excel file');               

                    if isequal(app.selected_file, 0)
                        close(h);
                        return; % Si se cancela la selección, vuelve a empezar
                    end
                    
                    app.ImporteddataTextArea.Value = app.selected_file;           
                    filePath = fullfile(path, app.selected_file);
                    
                    
                    for i = 1:app.NumberofTestsEditField.Value
                    sheetName = sprintf('Test %03d', i);
                    
                   % Verificar si la hoja existe antes de intentar leerla
                    sheetNames = sheetnames(filePath);

                        if any(strcmp(sheetNames, sheetName))
                            Test_sheet{i} = readtable(filePath, 'Sheet', sheetName, 'TextType', 'string');
                        else               
                            continue                
                        end

                    app.load{i} = Test_sheet{i}.LoadOnSample;
                    app.depth{i} = Test_sheet{i}.DisplacementIntoSurface;
                   
                    Condition_H = (Test_sheet{i}.Hardness >= app.MinimumHardnessEditField.Value & Test_sheet{i}.Hardness <= app.MaximumHardnessEditField.Value);
                    Condition_M = (Test_sheet{i}.Modulus >= app.MinimumModulusEditField.Value & Test_sheet{i}.Modulus <= app.MaximumModulusEditField.Value);                         
                    
                    D_values_H{i} = Test_sheet{i}.DisplacementIntoSurface(Condition_H);                 
                    D_values_M{i} = Test_sheet{i}.DisplacementIntoSurface(Condition_M);     
                    
                    app.numRows_H = nnz(Condition_H);
                    app.numRows_M = nnz(Condition_M);     

                    H_values_per_row(1:app.numRows_H, i) = Test_sheet{i}.Hardness(Condition_H);
                    M_values_per_row(1:app.numRows_M, i) = Test_sheet{i}.Modulus(Condition_M);
                    D_values_per_row_H(1:app.numRows_H, i) = D_values_H{i};             
                    D_values_per_row_M(1:app.numRows_M, i) = D_values_M{i}; 


                    app.maxNumRows = max(app.maxNumRows, max(app.numRows_H, app.numRows_M));
                    
                    % Actualiza la barra de progreso
                    waitbar(i / app.NumberofTestsEditField.Value, h, 'Importing and processing data...');
                        
                    end
                  
                    % parameters calculus            
                    % Calcular las estadísticas solo para los valores que cumplen con la condición
                    H_mean_per_row = mean(H_values_per_row(1:app.numRows_H, :), 2);
                    H_std_per_row = std(H_values_per_row(1:app.numRows_H, :), 0, 2);
                    M_mean_per_row = mean(M_values_per_row(1:app.numRows_M, :), 2);
                    M_std_per_row = std(M_values_per_row(1:app.numRows_M, :), 0, 2);
                    D_mean_per_row_H = mean(D_values_per_row_H(1:app.numRows_H, :), 2); 
                    D_std_per_row_H = std(D_values_per_row_H(1:app.numRows_H, :), 0, 2);     
                    D_mean_per_row_M = mean(D_values_per_row_M(1:app.numRows_M, :), 2); 
                    D_std_per_row_M = std(D_values_per_row_M(1:app.numRows_M, :), 0, 2);

                    % Limitar valores calculados en la media
                    condition_mask_H_mean = H_mean_per_row >= app.MinimumHardnessEditField.Value & H_mean_per_row <= app.MaximumHardnessEditField.Value;
                    condition_mask_M_mean = M_mean_per_row >= app.MinimumModulusEditField.Value & M_mean_per_row <= app.MaximumModulusEditField.Value;
                    condition_mask_D_mean_H = D_mean_per_row_H >= app.MinimumdepthEditField.Value & D_mean_per_row_H <= app.MaximumdepthEditField.Value;
                    condition_mask_D_mean_M = D_mean_per_row_M >= app.MinimumdepthEditField.Value & D_mean_per_row_M <= app.MaximumdepthEditField.Value;
                    
                    % Encontrar los índices comunes
                    common_indices_H = intersect(find(condition_mask_H_mean), find(condition_mask_D_mean_H));
                    common_indices_M = intersect(find(condition_mask_M_mean), find(condition_mask_D_mean_M));
                    
                    % Filtrar los valores que cumplen con la condición en H_mean_per_row y D_mean_per_row_H
                    app.H_mean_per_row_filtered = H_mean_per_row(common_indices_H);
                    app.H_std_per_row_filtered = H_std_per_row(common_indices_H);
                    
                    app.M_mean_per_row_filtered = M_mean_per_row(common_indices_M);
                    app.M_std_per_row_filtered = M_std_per_row(common_indices_M);
                    
                    app.D_mean_per_row_H_filtered = D_mean_per_row_H(common_indices_H);
                    app.D_std_per_row_H_filtered = D_std_per_row_H(common_indices_H);
                    
                    app.D_mean_per_row_M_filtered = D_mean_per_row_M(common_indices_M);
                    app.D_std_per_row_M_filtered = D_std_per_row_M(common_indices_M);

                close(h)
                msgbox('NanoXP data imported successfully', 'Success', 'modal')

                elseif app.iMicroButton_3.Value == 0 && app.NanoXPButton_3.Value == 0
                    msgbox('Nanoindenter selection empty. Please, select "iMicro" or "NanoXP"', 'Error', 'modal');
                    close(h)
            end

        end

        % Value changed function: ErrorSpacingEditField
        function ErrorSpacingEditFieldValueChanged(app, event)
            value = app.ErrorSpacingEditField.Value;          
        end

        % Button pushed function: PlotdatafromsinglefileButton
        function PlotdatafromsinglefileButtonPushed(app, event)
            cla(app.UIAxes);   
            cla(app.UIAxes2); 
            
            app.PlotdatafrommultiplefilesButton.Enable = "off";
            app.PlotdatafromsinglefileButton.Enable = "on";
            app.SeeinfullscreenButton_6.Visible = "on";
            app.SeeinfullscreenButton_7.Visible = "on";
            app.SeeinfullscreenButton_6.Enable = "on";
            app.SeeinfullscreenButton_7.Enable = "on";
            app.SeeinfullscreenButton_4.Visible = "off";
            app.SeeinfullscreenButton_5.Visible = "off";
            app.SeeinfullscreenButton_4.Enable = "off";
            app.SeeinfullscreenButton_5.Enable = "off";
            
            error_spacing = app.ErrorSpacingEditField.Value;
            error_indices_H = 1:error_spacing:length(app.D_mean_per_row_H_filtered);
            error_indices_M = 1:error_spacing:length(app.D_mean_per_row_M_filtered);

            app.LineColorDropDown.Enable = "on";


            if app.IncludeerrorbarsSwitch.Value == 0    
                app.MarkerColorDropDown.Enable = "off";
                app.MarkerEdgeColorDropDown.Enable = "off";
                app.LineMarkerStyleDropDown.Enable = "off";
                
                % Graficar barras de error cada cierto número de valores
                plot(app.UIAxes, app.D_mean_per_row_H_filtered, app.H_mean_per_row_filtered, 'LineWidth', app.LinewidthSlider.Value ,'DisplayName',app.selected_file)
                title(app.UIAxes,"Hardness vs Depth")
                xlabel(app.UIAxes,"Depth [nm]")
                ylabel(app.UIAxes,"Hardness [GPa]")
                
                % Repetir el proceso para Modulus vs Depth
    
                plot(app.UIAxes2, app.D_mean_per_row_M_filtered, app.M_mean_per_row_filtered, 'LineWidth', app.LinewidthSlider.Value, 'DisplayName',app.selected_file)
                title(app.UIAxes2,"Modulus vs Depth")
                xlabel(app.UIAxes2,"Depth [nm]")
                ylabel(app.UIAxes2,"Modulus [GPa]")
                
            elseif app.IncludeerrorbarsSwitch.Value == 1
                app.MarkerColorDropDown.Enable = "on";
                app.MarkerEdgeColorDropDown.Enable = "on";
                app.LineMarkerStyleDropDown.Enable = "on";
                
                errorbar(app.UIAxes, app.D_mean_per_row_H_filtered(error_indices_H), app.H_mean_per_row_filtered(error_indices_H), app.H_std_per_row_filtered(error_indices_H), app.LineMarkerStyleDropDown.Value, app.ErrorBarsDropDown.Value,"Color",app.LineColorDropDown.Value, "MarkerEdgeColor",app.MarkerEdgeColorDropDown.Value,"MarkerFaceColor", app.MarkerColorDropDown.Value, "MarkerSize",app.MarkerSizeSlider.Value, ...
                    "LineWidth",app.LinewidthSlider.Value, 'DisplayName', app.selected_file)
                
                title(app.UIAxes,"Hardness vs Depth")
                xlabel(app.UIAxes,"Depth[nm]")
                ylabel(app.UIAxes,"Hardness [GPa]")
                
            % Repetir el proceso para Modulus vs Depth
                errorbar(app.UIAxes2, app.D_mean_per_row_M_filtered(error_indices_M), app.M_mean_per_row_filtered(error_indices_M), app.M_std_per_row_filtered(error_indices_M), app.LineMarkerStyleDropDown.Value, app.ErrorBarsDropDown.Value,"Color",app.LineColorDropDown.Value, "MarkerEdgeColor",app.MarkerEdgeColorDropDown.Value,"MarkerFaceColor", app.MarkerColorDropDown.Value, "MarkerSize",app.MarkerSizeSlider.Value, ...
                    "LineWidth",app.LinewidthSlider.Value,'DisplayName', app.selected_file)
              
                title(app.UIAxes2,"Modulus vs Depth")
                xlabel(app.UIAxes2,"Depth [nm]")
                ylabel(app.UIAxes2,"Modulus [GPa]")
                
            end           
        end

        % Button pushed function: SavecleaneddatamultipletestButton
        function SavecleaneddatamultipletestButtonPushed(app, event)
            [savefile, savepath] = uiputfile('*.xlsx', 'Save Cleaned Data as Excel');

            if isequal(savefile, 0)
                return;
            end
            
            for i = 1:length(app.selected_files)
            % Crear la ruta completa para el nuevo archivo Excel
            saveFilePath = fullfile(savepath, savefile);

            cleaned_hardness = table(app.results_filtered(i).D_mean_per_row_H_filtered, app.results_filtered(i).H_mean_per_row_filtered, app.results_filtered(i).H_std_per_row_filtered, 'VariableNames',{'Displacement', 'Hardness mean','Hardness std'});
            cleaned_modulus = table(app.results_filtered(i).D_mean_per_row_M_filtered, app.results_filtered(i).M_mean_per_row_filtered, app.results_filtered(i).M_std_per_row_filtered, 'VariableNames',{'Displacement', 'Modulus mean','Modulus std'});

            writetable(cleaned_hardness, saveFilePath, 'Sheet', app.selected_files{i}, 'Range', 'A1');
            writetable(cleaned_modulus, saveFilePath, 'Sheet', app.selected_files{i}, 'Range', 'E1');

            msgbox(['Cleaned data saved in' saveFilePath], 'Success', 'modal');
            end
        
        end

        % Button pushed function: SavecleaneddatasingletestButton
        function SavecleaneddatasingletestButtonPushed(app, event)
             [savefile, savepath] = uiputfile('*.xlsx', 'Save Cleaned Data as Excel');

            if isequal(savefile, 0)
                return;
            end
            
            % single test
            saveFilePath = fullfile(savepath, savefile);
                      
            cleaned_hardness = table(app.D_mean_per_row_H_filtered, app.H_mean_per_row_filtered, app.H_std_per_row_filtered, 'VariableNames',{'Displacement', 'Hardness mean','Hardness std'});
            cleaned_modulus = table(app.D_mean_per_row_M_filtered, app.M_mean_per_row_filtered, app.M_std_per_row_filtered, 'VariableNames',{'Displacement', 'Modulus mean','Modulus std'});
            
            writetable(cleaned_hardness, saveFilePath, 'Sheet', app.selected_file, 'Range', 'A1');
            writetable(cleaned_modulus, saveFilePath, 'Sheet', app.selected_file, 'Range', 'E1');

            msgbox(['Cleaned data saved in' saveFilePath], 'Success', 'modal');

        end

        % Button pushed function: ImportmultiplefilesButton
        function ImportmultiplefilesButtonPushed(app, event)
            app.SavecleaneddatamultipletestButton.Enable = "on";
            app.SavecleaneddatasingletestButton.Enable = "on";
            app.PlotdatafrommultiplefilesButton.Enable = "on";
            app.PlotdatafromsinglefileButton.Enable = "off";            
            app.PlotdatafrommultiplefilesButton.Enable = "on";
            app.ErrorBarsDropDown.Enable ="on";
            app.MarkerColorDropDown.Enable = "on";
            app.LineMarkerStyleDropDown.Enable ="on";
            app.MarkerEdgeColorDropDown.Enable ="on";
            app.UIAxes4.Visible = "off";
            app.PlotPhcurveonlyforsingletestButton.Enable = "off";

            app.maxNumRows = 0;
            
            h = waitbar(0, 'Importing and processing data...');
            
            if app.NumberofTestsEditField.Value == 0
                close(h);
                msgbox('Number of tests can not be 0', 'Error', 'modal')
                return;
            end
            
            % Selección de archivos Excel
            [app.selected_files, path] = uigetfile('*.xlsx;*.xls', 'Select Excel files', 'MultiSelect', 'on');
            
            if isequal(app.selected_files, 0)               
                return;
            end
     
            results = struct('H_mean_per_row', cell(length(app.selected_files), 1), 'H_std_per_row', cell(length(app.selected_files), 1), ...
                 'M_mean_per_row', cell(length(app.selected_files), 1), 'M_std_per_row', cell(length(app.selected_files), 1), ...
                 'D_mean_per_row_H', cell(length(app.selected_files), 1), 'D_std_per_row_H', cell(length(app.selected_files), 1), ...
                 'D_mean_per_row_M', cell(length(app.selected_files), 1), 'D_std_per_row_M', cell(length(app.selected_files), 1), ...
                 'Load', cell(length(app.selected_files), 1), 'Depth', cell(length(app.selected_files), 1));

            app.results_filtered = struct('H_mean_per_row_filered', cell(length(app.selected_files), 1), 'H_std_per_row_filered', cell(length(app.selected_files), 1), ...
                 'M_mean_per_row_filered', cell(length(app.selected_files), 1), 'M_std_per_row_filered', cell(length(app.selected_files), 1), ...
                 'D_mean_per_row_H_filered', cell(length(app.selected_files), 1), 'D_std_per_row_H_filered', cell(length(app.selected_files), 1), ...
                 'D_mean_per_row_M_filered', cell(length(app.selected_files), 1), 'D_std_per_row_M_filered', cell(length(app.selected_files), 1));          
            
            app.ImporteddataTextArea.Value = strjoin(app.selected_files, ', ');                      
      
            for i = 1:length(app.selected_files)                  
                filePath = fullfile(path, app.selected_files{i});  
            
                if app.iMicroButton_3.Value == 1                   
                    for j = 1:app.NumberofTestsEditField.Value
                        
                        sheetName = "Test " + num2str(j);   % Definición del nombre de las hojas del Excel que interesan                                    
                        % Verificar si la hoja existe antes de intentar leerla
                        sheetNames = sheetnames(filePath);    

                        if any(strcmp(sheetNames, sheetName))
                           Test_sheet = readtable(filePath, 'Sheet', sheetName, 'TextType', 'string');                                          
                        else
                           continue
                        end 
                                 
                        app.load{j} = Test_sheet.LOAD;
                        app.depth{j} = Test_sheet.DEPTH;
                        
                        % Outliers values cleaning (HARDNESS and MODULUS)
                        Condition_H = (Test_sheet.HARDNESS >= app.MinimumHardnessEditField.Value & Test_sheet.HARDNESS <= app.MaximumHardnessEditField.Value);
                        Condition_M = (Test_sheet.MODULUS >= app.MinimumModulusEditField.Value & Test_sheet.MODULUS <= app.MaximumModulusEditField.Value);             
     
                        D_values_H = Test_sheet.DEPTH(Condition_H);
                        D_values_M = Test_sheet.DEPTH(Condition_M);

                        app.numRows_H = nnz(Condition_H);
                        app.numRows_M = nnz(Condition_M);
                      
                        H_values_per_row(1:app.numRows_H, j) = Test_sheet.HARDNESS(Condition_H);
                        M_values_per_row(1:app.numRows_M, j) = Test_sheet.MODULUS(Condition_M);
                        D_values_per_row_H(1:app.numRows_H, j) = D_values_H;
                        D_values_per_row_M(1:app.numRows_M, j) = D_values_M;           
                            
                        app.maxNumRows = max(app.maxNumRows, max(app.numRows_H, app.numRows_M));
 
                        results(i).Load{j} = app.load{j};
                        results(i).Depth{j} = app.depth{j};
                 
                    end
                                                      
                    results(i).H_mean_per_row = mean(H_values_per_row(1:app.numRows_H, :), 2);
                    results(i).H_std_per_row = std(H_values_per_row(1:app.numRows_H, :), 0, 2);
                        
                    results(i).M_mean_per_row = mean(M_values_per_row(1:app.numRows_M, :), 2);
                    results(i).M_std_per_row = std(M_values_per_row(1:app.numRows_M, :), 0, 2);
                        
                    results(i).D_mean_per_row_H = mean(D_values_per_row_H(1:app.numRows_H, :), 2);
                    results(i).D_std_per_row_H = std(D_values_per_row_H(1:app.numRows_H, :), 0, 2);
                        
                    results(i).D_mean_per_row_M = mean(D_values_per_row_M(1:app.numRows_M, :), 2);
                    results(i).D_std_per_row_M = std(D_values_per_row_M(1:app.numRows_M, :), 0, 2);

                    [~, filename, ~] = fileparts(app.selected_files{i});
                    app.selected_files{i} = filename;
                    
                    % Limitar valores calculados en la media
                    condition_mask_H_mean = results(i).H_mean_per_row >= app.MinimumHardnessEditField.Value & results(i).H_mean_per_row <= app.MaximumHardnessEditField.Value;
                    condition_mask_M_mean = results(i).M_mean_per_row >= app.MinimumModulusEditField.Value & results(i).M_mean_per_row <= app.MaximumModulusEditField.Value;
                    condition_mask_D_mean_H = results(i).D_mean_per_row_H >= app.MinimumdepthEditField.Value & results(i).D_mean_per_row_H <= app.MaximumdepthEditField.Value;
                    condition_mask_D_mean_M = results(i).D_mean_per_row_M >= app.MinimumdepthEditField.Value & results(i).D_mean_per_row_M <= app.MaximumdepthEditField.Value;
                
                    % Encontrar los índices comunes
                    common_indices_H = intersect(find(condition_mask_H_mean), find(condition_mask_D_mean_H));
                    common_indices_M = intersect(find(condition_mask_M_mean), find(condition_mask_D_mean_M));
                
                    % Filtrar los valores que cumplen con la condición en H_mean_per_row y D_mean_per_row_H
                    app.results_filtered(i).H_mean_per_row_filtered = results(i).H_mean_per_row(common_indices_H);
                    app.results_filtered(i).H_std_per_row_filtered = results(i).H_std_per_row(common_indices_H);
                    
                    app.results_filtered(i).M_mean_per_row_filtered = results(i).M_mean_per_row(common_indices_M);
                    app.results_filtered(i).M_std_per_row_filtered = results(i).M_std_per_row(common_indices_M);
                    
                    app.results_filtered(i).D_mean_per_row_H_filtered = results(i).D_mean_per_row_H(common_indices_H);
                    app.results_filtered(i).D_std_per_row_H_filtered = results(i).D_std_per_row_H(common_indices_H);
                    
                    app.results_filtered(i).D_mean_per_row_M_filtered = results(i).D_mean_per_row_M(common_indices_M);
                    app.results_filtered(i).D_std_per_row_M_filtered = results(i).D_std_per_row_M(common_indices_M);
                        
                    % Actualiza la barra de progreso
                    waitbar(i / length(app.selected_files), h, 'Importing and processing data...');

                elseif app.NanoXPButton_3.Value == 1
                   
                    for j = 1:app.NumberofTestsEditField.Value
                        

                        sheetName = sprintf('Test %03d', j);
                        sheetNames = sheetnames(filePath);

                        if any(strcmp(sheetNames, sheetName))
                           Test_sheet = readtable(filePath, 'Sheet', sheetName, 'TextType', 'string');                                          
                        else
                           continue
                        end 
                        
                        app.ImporteddataTextArea.Value = strjoin(app.selected_files, ', ');               
            
                        app.load{j} = Test_sheet.LoadOnSample;
                        app.depth{j} = Test_sheet.DisplacementIntoSurface;
                            
                        % Outliers values cleaning (HARDNESS and MODULUS)
                        Condition_H = (Test_sheet.Hardness >= app.MinimumHardnessEditField.Value & Test_sheet.Hardness <= app.MaximumHardnessEditField.Value);
                        Condition_M = (Test_sheet.Modulus >= app.MinimumModulusEditField.Value & Test_sheet.Modulus <= app.MaximumModulusEditField.Value);             
    
                        D_values_H = Test_sheet.DisplacementIntoSurface(Condition_H);
                        D_values_M = Test_sheet.DisplacementIntoSurface(Condition_M); 
                        
                        app.numRows_H = nnz(Condition_H);
                        app.numRows_M = nnz(Condition_M);
                      
                        H_values_per_row(1:app.numRows_H, j) = Test_sheet.Hardness(Condition_H);
                        M_values_per_row(1:app.numRows_M, j) = Test_sheet.Modulus(Condition_M);
                        D_values_per_row_H(1:app.numRows_H, j) = D_values_H;
                        D_values_per_row_M(1:app.numRows_M, j) = D_values_M;                                       
                        
                        app.maxNumRows = max(app.maxNumRows, max(app.numRows_H, app.numRows_M));
    
                        results(i).Load{j} = app.load{j};
                        results(i).Depth{j} = app.depth{j};                        
                    end
       
                    results(i).H_mean_per_row = mean(H_values_per_row(1:app.numRows_H, :), 2);
                    results(i).H_std_per_row = std(H_values_per_row(1:app.numRows_H, :), 0, 2);
                        
                    results(i).M_mean_per_row = mean(M_values_per_row(1:app.numRows_M, :), 2);
                    results(i).M_std_per_row = std(M_values_per_row(1:app.numRows_M, :), 0, 2);
                        
                    results(i).D_mean_per_row_H = mean(D_values_per_row_H(1:app.numRows_H, :), 2);
                    results(i).D_std_per_row_H = std(D_values_per_row_H(1:app.numRows_H, :), 0, 2);
                        
                    results(i).D_mean_per_row_M = mean(D_values_per_row_M(1:app.numRows_M, :), 2);
                    results(i).D_std_per_row_M = std(D_values_per_row_M(1:app.numRows_M, :), 0, 2);

                    [~, filename, ~] = fileparts(app.selected_files{i});
                    app.selected_files{i} = filename;
                    
                    % Limitar valores calculados en la media
                    condition_mask_H_mean = results(i).H_mean_per_row >= app.MinimumHardnessEditField.Value & results(i).H_mean_per_row <= app.MaximumHardnessEditField.Value;
                    condition_mask_M_mean = results(i).M_mean_per_row >= app.MinimumModulusEditField.Value & results(i).M_mean_per_row <= app.MaximumModulusEditField.Value;
                    condition_mask_D_mean_H = results(i).D_mean_per_row_H >= app.MinimumdepthEditField.Value & results(i).D_mean_per_row_H <= app.MaximumdepthEditField.Value;
                    condition_mask_D_mean_M = results(i).D_mean_per_row_M >= app.MinimumdepthEditField.Value & results(i).D_mean_per_row_M <= app.MaximumdepthEditField.Value;
                
                    % Encontrar los índices comunes
                    common_indices_H = intersect(find(condition_mask_H_mean), find(condition_mask_D_mean_H));
                    common_indices_M = intersect(find(condition_mask_M_mean), find(condition_mask_D_mean_M));
                
                    % Filtrar los valores que cumplen con la condición en H_mean_per_row y D_mean_per_row_H
                    app.results_filtered(i).H_mean_per_row_filtered = results(i).H_mean_per_row(common_indices_H);
                    app.results_filtered(i).H_std_per_row_filtered = results(i).H_std_per_row(common_indices_H);
                    
                    app.results_filtered(i).M_mean_per_row_filtered = results(i).M_mean_per_row(common_indices_M);
                    app.results_filtered(i).M_std_per_row_filtered = results(i).M_std_per_row(common_indices_M);
                    
                    app.results_filtered(i).D_mean_per_row_H_filtered = results(i).D_mean_per_row_H(common_indices_H);
                    app.results_filtered(i).D_std_per_row_H_filtered = results(i).D_std_per_row_H(common_indices_H);
                    
                    app.results_filtered(i).D_mean_per_row_M_filtered = results(i).D_mean_per_row_M(common_indices_M);
                    app.results_filtered(i).D_std_per_row_M_filtered = results(i).D_std_per_row_M(common_indices_M);   

                    % Actualiza la barra de progreso
                    waitbar(i / length(app.selected_files), h, 'Importing and processing data...');
                    
                elseif app.iMicroButton_3.Value == 0 && app.NanoXPButton_3.Value == 0
                    msgbox('Nanoindenter selection empty. Please, select "iMicro" or "NanoXP"', 'Error', 'modal');
                    close(h)
                end
                
            end
            close(h)
            msgbox('Data imported successfully', 'Success', 'modal')
        end

        % Button pushed function: PlotdatafrommultiplefilesButton
        function PlotdatafrommultiplefilesButtonPushed(app, event)
            cla(app.UIAxes);   
            cla(app.UIAxes2);
            
            app.MarkerColorDropDown.Enable = "off";
            app.MarkerEdgeColorDropDown.Enable = "off";
            app.LineColorDropDown.Enable = "off";
            app.SeeinfullscreenButton_4.Visible = "on";
            app.SeeinfullscreenButton_5.Visible = "on";
            app.SeeinfullscreenButton_4.Enable = "on";
            app.SeeinfullscreenButton_5.Enable = "on";
            app.SeeinfullscreenButton_6.Enable = "off";
            app.SeeinfullscreenButton_7.Enable = "off";
            app.SeeinfullscreenButton_6.Visible = "off";
            app.SeeinfullscreenButton_7.Visible = "off";
            
            
            line_colors = {'Green', 'Red', 'Blue', 'Magenta', 'Cyan', 'Yellow', 'Black', 'Gray'};
            num_colors = length(line_colors);

            for i = 1:length(app.selected_files)
                error_indices_H = 1:app.ErrorSpacingEditField.Value:length(app.results_filtered(i).D_mean_per_row_H_filtered);
                error_indices_M = 1:app.ErrorSpacingEditField.Value:length(app.results_filtered(i).D_mean_per_row_M_filtered);
                
                current_color = line_colors{mod(i, num_colors) + 1};
                
                if app.IncludeerrorbarsSwitch.Value == 0                       
                    hold(app.UIAxes, 'on');
                    hold(app.UIAxes2, 'on');             

                    plot(app.UIAxes, app.results_filtered(i).D_mean_per_row_H_filtered, app.results_filtered(i).H_mean_per_row_filtered,'LineWidth', app.LinewidthSlider.Value,'DisplayName', app.selected_files{i})
                    title(app.UIAxes,"Hardness vs Depth")
                    xlabel(app.UIAxes,"Displacement into Surface [nm]")
                    ylabel(app.UIAxes,"Hardness [GPa]")                 
                              
                    plot(app.UIAxes2, app.results_filtered(i).D_mean_per_row_M_filtered, app.results_filtered(i).M_mean_per_row_filtered,'LineWidth', app.LinewidthSlider.Value,'DisplayName', app.selected_files{i})
                    title(app.UIAxes2,"Modulus vs Depth")
                    xlabel(app.UIAxes2,"Displacement into Surface [nm]")
                    ylabel(app.UIAxes2,"Modulus [GPa]")                  
          
                    hold(app.UIAxes, 'off');
                    hold(app.UIAxes2, 'off');

                    legend(app.UIAxes, 'Location', 'best');
                    legend(app.UIAxes2, 'Location', 'best');

                elseif app.IncludeerrorbarsSwitch.Value == 1                                   
                    hold(app.UIAxes, 'on');
                    hold(app.UIAxes2, 'on');

                    errorbar(app.UIAxes, app.results_filtered(i).D_mean_per_row_H_filtered(error_indices_H), app.results_filtered(i).H_mean_per_row_filtered(error_indices_H), app.results_filtered(i).H_std_per_row_filtered(error_indices_H), app.LineMarkerStyleDropDown.Value, ...
                         app.ErrorBarsDropDown.Value, "Color", current_color, "MarkerEdgeColor", current_color, "MarkerFaceColor", current_color, "MarkerSize", app.MarkerSizeSlider.Value, ...
                          "LineWidth", app.LinewidthSlider.Value, 'DisplayName', app.selected_files{i})

                    title(app.UIAxes,"Hardness vs Depth")
                    xlabel(app.UIAxes,"Displacement into Surface [nm]")
                    ylabel(app.UIAxes,"Hardness [GPa]")
                    
                
                    errorbar(app.UIAxes2, app.results_filtered(i).D_mean_per_row_M_filtered(error_indices_M), app.results_filtered(i).M_mean_per_row_filtered(error_indices_M), app.results_filtered(i).M_std_per_row_filtered(error_indices_M), app.LineMarkerStyleDropDown.Value, ...
                        app.ErrorBarsDropDown.Value, "Color", current_color, "MarkerEdgeColor", current_color, "MarkerFaceColor", current_color, "MarkerSize", app.MarkerSizeSlider.Value, ...
                         "LineWidth", app.LinewidthSlider.Value, 'DisplayName', app.selected_files{i})
                  
                    title(app.UIAxes2,"Modulus vs Depth")
                    xlabel(app.UIAxes2,"Displacement into Surface [nm]")
                    ylabel(app.UIAxes2,"Modulus [GPa]")

                    hold(app.UIAxes, 'off');
                    hold(app.UIAxes2, 'off');
                    
                    legend(app.UIAxes, 'Location', 'best');
                    legend(app.UIAxes2, 'Location', 'best');
                end
        end  
            
        end

        % Value changed function: ImporteddataTextArea
        function ImporteddataTextAreaValueChanged(app, event)
            value = app.ImporteddataTextArea.Value;
            
        end

        % Value changed function: MinimumdepthEditField
        function MinimumdepthEditFieldValueChanged(app, event)
            value = app.MinimumdepthEditField.Value;
            
        end

        % Value changed function: MaximumdepthEditField
        function MaximumdepthEditFieldValueChanged(app, event)
            value = app.MaximumdepthEditField.Value;
            
        end

        % Value changed function: ErrorBarsDropDown
        function ErrorBarsDropDownValueChanged(app, event)
            value = app.ErrorBarsDropDown.Value;
            
        end

        % Value changed function: MarkerEdgeColorDropDown
        function MarkerEdgeColorDropDownValueChanged(app, event)
            value = app.MarkerEdgeColorDropDown.Value;
            
        end

        % Value changed function: LineColorDropDown
        function LineColorDropDownValueChanged(app, event)
            value = app.LineColorDropDown.Value;
            
        end

        % Value changed function: MarkerSizeSlider
        function MarkerSizeSliderValueChanged(app, event)
            value = app.MarkerSizeSlider.Value;
            
        end

        % Value changed function: LinewidthSlider
        function LinewidthSliderValueChanged(app, event)
            value = app.LinewidthSlider.Value;
            
        end

        % Value changed function: IncludeerrorbarsSwitch
        function IncludeerrorbarsSwitchValueChanged(app, event)
            value = app.IncludeerrorbarsSwitch.Value;
            
        end

        % Value changed function: LineMarkerStyleDropDown
        function LineMarkerStyleDropDownValueChanged(app, event)
            value = app.LineMarkerStyleDropDown.Value;
            
        end

        % Value changed function: MarkerColorDropDown
        function MarkerColorDropDownValueChanged(app, event)
            value = app.MarkerColorDropDown.Value;
            
        end

        % Button pushed function: PlotPhcurveonlyforsingletestButton
        function PlotPhcurveonlyforsingletestButtonPushed(app, event)
            app.SeeinfullscreenButton_3.Visible = "on";
            app.SeeinfullscreenButton_3.Enable = "on";
            
            cla(app.UIAxes4);
            [~, filename, ~] = fileparts(app.selected_file);
            app.selected_file = filename;
            
            legend(app.UIAxes4, 'off');
            hold(app.UIAxes4, "on");
            for j = 1:app.NumberofTestsEditField.Value
                    
                if j > numel(app.depth{j}) || j > numel(app.load{j})
                    continue; % Saltar al siguiente índice si j excede el número de elementos
                end
        
                % Verificar la existencia de datos para este índice j    
                if isempty(app.depth{j}) || isempty(app.load{j})            
                    continue; % Saltar al siguiente índice si no hay datos        
                end
               
                plot(app.UIAxes4, app.depth{j}, app.load{j},'DisplayName', sprintf('Test %d', j))
                    
            end
            hold(app.UIAxes4, "off")

            title(app.UIAxes4,"P-h curve " + app.selected_file)
            xlabel(app.UIAxes4,"Displacement into Surface [nm]")
            ylabel(app.UIAxes4,"Load [mN]")
            legend(app.UIAxes4,'Location', 'east')
            

        end

        % Button pushed function: SeeinfullscreenButton_3
        function SeeinfullscreenButton_3Pushed(app, event)
            
            [~, filename, ~] = fileparts(app.selected_file);
            app.selected_file = filename;
            
            legend(app.UIAxes4, 'off');
            figure;
            hold on
            for j = 1:app.NumberofTestsEditField.Value
                    
                if j > numel(app.depth{j}) || j > numel(app.load{j})
                    continue; % Saltar al siguiente índice si j excede el número de elementos
                end
        
                % Verificar la existencia de datos para este índice j    
                if isempty(app.depth{j}) || isempty(app.load{j})            
                    continue; % Saltar al siguiente índice si no hay datos        
                end
               
                plot(app.depth{j}, app.load{j},'DisplayName', sprintf('Test %d', j))
                    
            end
            hold off
            title("P-h curve " + app.selected_file)
            xlabel("Displacement into Surface [nm]")
            ylabel("Load [mN]")
            legend('Location', 'east')
        end

        % Button pushed function: SeeinfullscreenButton_4
        function SeeinfullscreenButton_4Pushed(app, event)

            app.MarkerColorDropDown.Enable = "off";
            app.MarkerEdgeColorDropDown.Enable = "off";
            app.LineColorDropDown.Enable = "off";
                        
            line_colors = {'Green', 'Red', 'Blue', 'Magenta', 'Cyan', 'Yellow', 'Black', 'Gray'};
            num_colors = length(line_colors);

            figure;
            
            for i = 1:length(app.selected_files)
                error_indices_H = 1:app.ErrorSpacingEditField.Value:length(app.results_filtered(i).D_mean_per_row_H_filtered);
                error_indices_M = 1:app.ErrorSpacingEditField.Value:length(app.results_filtered(i).D_mean_per_row_M_filtered);
                
                current_color = line_colors{mod(i, num_colors) + 1};
                
                if app.IncludeerrorbarsSwitch.Value == 0                       
                    hold('on');             

                    plot(app.results_filtered(i).D_mean_per_row_H_filtered, app.results_filtered(i).H_mean_per_row_filtered, 'LineWidth', app.LinewidthSlider.Value,'DisplayName', app.selected_files{i})
                    title("Hardness vs Depth")
                    xlabel("Displacement into Surface [nm]")
                    ylabel("Hardness [GPa]")                 
                              
                    hold('off');              
                    legend('Location', 'best');
            
                elseif app.IncludeerrorbarsSwitch.Value == 1                                   
                    hold('on');

                    errorbar(app.results_filtered(i).D_mean_per_row_H_filtered(error_indices_H), app.results_filtered(i).H_mean_per_row_filtered(error_indices_H), app.results_filtered(i).H_std_per_row_filtered(error_indices_H), app.LineMarkerStyleDropDown.Value, ...
                         app.ErrorBarsDropDown.Value, "Color", current_color, "MarkerEdgeColor", current_color, "MarkerFaceColor", current_color, "MarkerSize", app.MarkerSizeSlider.Value, ...
                          "LineWidth", app.LinewidthSlider.Value, 'DisplayName', app.selected_files{i})
                
                    title("Hardness vs Depth")
                    xlabel("Displacement into Surface [nm]")
                    ylabel("Hardness [GPa]")

                    hold('off');                                    
                    legend('Location', 'best');                    
                end
        end  
        end

        % Button pushed function: SeeinfullscreenButton_5
        function SeeinfullscreenButton_5Pushed(app, event)
            
            app.MarkerColorDropDown.Enable = "off";
            app.MarkerEdgeColorDropDown.Enable = "off";
            app.LineColorDropDown.Enable = "off";
            
            line_colors = {'Green', 'Red', 'Blue', 'Magenta', 'Cyan', 'Yellow', 'Black', 'Gray'};
            num_colors = length(line_colors);
            
            figure;

            for i = 1:length(app.selected_files)
                error_indices_H = 1:app.ErrorSpacingEditField.Value:length(app.results_filtered(i).D_mean_per_row_H_filtered);
                error_indices_M = 1:app.ErrorSpacingEditField.Value:length(app.results_filtered(i).D_mean_per_row_M_filtered);
                
                current_color = line_colors{mod(i, num_colors) + 1};
                
                if app.IncludeerrorbarsSwitch.Value == 0                       
                    hold('on');          
                              
                    plot(app.results_filtered(i).D_mean_per_row_M_filtered, app.results_filtered(i).M_mean_per_row_filtered, 'LineWidth', app.LinewidthSlider.Value,'DisplayName', app.selected_files{i})
                    title("Modulus vs Depth")
                    xlabel("Displacement into Surface [nm]")
                    ylabel("Modulus [GPa]")                  
          
                    hold('off');
                    legend('Location', 'best');

                elseif app.IncludeerrorbarsSwitch.Value == 1                                   
                    hold('on');
                                   
                    errorbar(app.results_filtered(i).D_mean_per_row_M_filtered(error_indices_M), app.results_filtered(i).M_mean_per_row_filtered(error_indices_M), app.results_filtered(i).M_std_per_row_filtered(error_indices_M), app.LineMarkerStyleDropDown.Value, ...
                        app.ErrorBarsDropDown.Value, "Color", current_color, "MarkerEdgeColor", current_color, "MarkerFaceColor", current_color, "MarkerSize", app.MarkerSizeSlider.Value, ...
                         "LineWidth", app.LinewidthSlider.Value, 'DisplayName', app.selected_files{i})
                  
                    title("Modulus vs Depth")
                    xlabel("Displacement into Surface [nm]")
                    ylabel("Modulus [GPa]")

                    hold('off');
                    legend('Location', 'best');
                end
        end  
        end

        % Button pushed function: SeeinfullscreenButton_6
        function SeeinfullscreenButton_6Pushed(app, event)

            app.PlotdatafrommultiplefilesButton.Enable = "off";
            app.PlotdatafromsinglefileButton.Enable = "on";
            
            error_spacing = app.ErrorSpacingEditField.Value;
            error_indices_H = 1:error_spacing:length(app.D_mean_per_row_H_filtered);
            error_indices_M = 1:error_spacing:length(app.D_mean_per_row_M_filtered);

            app.MarkerColorDropDown.Enable = "on";
            app.MarkerEdgeColorDropDown.Enable = "on";
            app.LineColorDropDown.Enable = "on";

            if app.IncludeerrorbarsSwitch.Value == 0            
                % Graficar barras de error cada cierto número de valores
                plot(app.D_mean_per_row_H_filtered, app.H_mean_per_row_filtered, 'LineWidth', app.LinewidthSlider.Value ,'DisplayName',app.selected_file)
                title("Hardness vs Depth")
                xlabel("Depth [nm]")
                ylabel("Hardness [GPa]")
                legend('Location', 'best')
            elseif app.IncludeerrorbarsSwitch.Value == 1
                errorbar(app.D_mean_per_row_H_filtered(error_indices_H), app.H_mean_per_row_filtered(error_indices_H), app.H_std_per_row_filtered(error_indices_H), app.LineMarkerStyleDropDown.Value, app.ErrorBarsDropDown.Value,"Color",app.LineColorDropDown.Value, "MarkerEdgeColor",app.MarkerEdgeColorDropDown.Value,"MarkerFaceColor", app.MarkerColorDropDown.Value, "MarkerSize",app.MarkerSizeSlider.Value, ...
                    "LineWidth",app.LinewidthSlider.Value, 'DisplayName', app.selected_file)
                
                title("Hardness vs Depth")
                xlabel("Depth [nm]")
                ylabel("Hardness [GPa]")
                legend('Location', 'best')
                              
            end           
        end

        % Button pushed function: SeeinfullscreenButton_7
        function SeeinfullscreenButton_7Pushed(app, event)
               
            app.PlotdatafrommultiplefilesButton.Enable = "off";
            app.PlotdatafromsinglefileButton.Enable = "on";
            
            error_spacing = app.ErrorSpacingEditField.Value;
            error_indices_H = 1:error_spacing:length(app.D_mean_per_row_H_filtered);
            error_indices_M = 1:error_spacing:length(app.D_mean_per_row_M_filtered);

            app.MarkerColorDropDown.Enable = "on";
            app.MarkerEdgeColorDropDown.Enable = "on";
            app.LineColorDropDown.Enable = "on";

            figure; 

            if app.IncludeerrorbarsSwitch.Value == 0              
                plot(app.D_mean_per_row_M_filtered, app.M_mean_per_row_filtered, 'LineWidth', app.LinewidthSlider.Value, 'DisplayName',app.selected_file)
                title("Modulus vs Depth")
                xlabel("Depth [nm]")
                ylabel("Modulus [GPa]")
                legend('Location', 'best');

            elseif app.IncludeerrorbarsSwitch.Value == 1
                errorbar(app.D_mean_per_row_M_filtered(error_indices_M), app.M_mean_per_row_filtered(error_indices_M), app.M_std_per_row_filtered(error_indices_M), app.LineMarkerStyleDropDown.Value, app.ErrorBarsDropDown.Value,"Color",app.LineColorDropDown.Value, "MarkerEdgeColor",app.MarkerEdgeColorDropDown.Value,"MarkerFaceColor", app.MarkerColorDropDown.Value, "MarkerSize",app.MarkerSizeSlider.Value, ...
                    "LineWidth",app.LinewidthSlider.Value,'DisplayName', app.selected_file)
              
                title("Modulus vs Depth")
                xlabel("Depth [nm]")
                ylabel("Modulus [GPa]")
                legend('Location', 'best');

            end           
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1010, 1010};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {342, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DatacleaningLabel = uilabel(app.GridLayout2);
            app.DatacleaningLabel.Text = 'Data cleaning';
            app.PlotsLabel = uilabel(app.GridLayout2);
            app.PlotsLabel.Text = 'Plots';
            app.FrancescBarberFlichiLabel = uilabel(app.GridLayout2);
            app.FrancescBarberFlichiLabel.Text = 'Francesc Barberá Flichi';
            app.francescbarberaupceduLabel = uilabel(app.GridLayout2);
            app.francescbarberaupceduLabel.Text = 'francesc.barbera@upc.edu';
            app.CONTROLPANELLabel = uilabel(app.GridLayout2);
            app.CONTROLPANELLabel.Text = 'CONTROL PANEL';
            app.PlotdatafromsinglefileButton = uibutton(app.GridLayout2, 'push');
            app.PlotdatafromsinglefileButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdatafromsinglefileButtonPushed, true);
            app.PlotdatafromsinglefileButton.Enable = 'off';
            app.PlotdatafromsinglefileButton.Text = 'Plot data from single file';
            app.SavecleaneddatamultipletestButton = uibutton(app.GridLayout2, 'push');
            app.SavecleaneddatamultipletestButton.ButtonPushedFcn = createCallbackFcn(app, @SavecleaneddatamultipletestButtonPushed, true);
            app.SavecleaneddatamultipletestButton.Enable = 'off';
            app.SavecleaneddatamultipletestButton.Text = 'Save cleaned data (multiple test)';
            app.SelecttheindenterusedLabel = uilabel(app.GridLayout2);
            app.SelecttheindenterusedLabel.Text = 'Select the indenter used:';
            app.ImportsinglefileButton = uibutton(app.GridLayout2, 'push');
            app.ImportsinglefileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsinglefileButtonPushed, true);
            app.ImportsinglefileButton.Enable = 'off';
            app.ImportsinglefileButton.Text = 'Import single file';
            app.iMicroButton_3 = uibutton(app.GridLayout2, 'state');
            app.iMicroButton_3.ValueChangedFcn = createCallbackFcn(app, @iMicroButton_3ValueChanged, true);
            app.iMicroButton_3.Text = 'iMicro';
            app.NanoXPButton_3 = uibutton(app.GridLayout2, 'state');
            app.NanoXPButton_3.ValueChangedFcn = createCallbackFcn(app, @NanoXPButton_3ValueChanged, true);
            app.NanoXPButton_3.Text = 'NanoXP';
            app.ImportmultiplefilesButton = uibutton(app.GridLayout2, 'push');
            app.ImportmultiplefilesButton.ButtonPushedFcn = createCallbackFcn(app, @ImportmultiplefilesButtonPushed, true);
            app.ImportmultiplefilesButton.Enable = 'off';
            app.ImportmultiplefilesButton.Text = 'Import multiple files';
            app.PlotdatafrommultiplefilesButton = uibutton(app.GridLayout2, 'push');
            app.PlotdatafrommultiplefilesButton.ButtonPushedFcn = createCallbackFcn(app, @PlotdatafrommultiplefilesButtonPushed, true);
            app.PlotdatafrommultiplefilesButton.Enable = 'off';
            app.PlotdatafrommultiplefilesButton.Text = 'Plot data from multiple files';
            app.SavecleaneddatasingletestButton = uibutton(app.GridLayout2, 'push');
            app.SavecleaneddatasingletestButton.ButtonPushedFcn = createCallbackFcn(app, @SavecleaneddatasingletestButtonPushed, true);
            app.SavecleaneddatasingletestButton.Enable = 'off';
            app.SavecleaneddatasingletestButton.Text = 'Save cleaned data (single test)';
            app.PlotPhcurveonlyforsingletestButton = uibutton(app.GridLayout2, 'push');
            app.PlotPhcurveonlyforsingletestButton.ButtonPushedFcn = createCallbackFcn(app, @PlotPhcurveonlyforsingletestButtonPushed, true);
            app.PlotPhcurveonlyforsingletestButton.Enable = 'off';
            app.PlotPhcurveonlyforsingletestButton.Text = 'Plot P-h curve (only for single test)';
            app.NumberofTestsEditFieldLabel = uilabel(app.GridLayout2);
            app.NumberofTestsEditFieldLabel.Text = 'Number of Tests';
            app.NumberofTestsEditField = uieditfield(app.GridLayout2, 'numeric');
            app.NumberofTestsEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofTestsEditFieldValueChanged, true);
            app.MinimumHardnessEditFieldLabel = uilabel(app.GridLayout2);
            app.MinimumHardnessEditFieldLabel.Text = 'Minimum Hardness';
            app.MinimumHardnessEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinimumHardnessEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumHardnessEditFieldValueChanged, true);
            app.MaximumHardnessEditFieldLabel = uilabel(app.GridLayout2);
            app.MaximumHardnessEditFieldLabel.Text = 'Maximum Hardness';
            app.MaximumHardnessEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaximumHardnessEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumHardnessEditFieldValueChanged, true);
            app.MaximumHardnessEditField.Value = 1000;
            app.MinimumModulusEditFieldLabel = uilabel(app.GridLayout2);
            app.MinimumModulusEditFieldLabel.Text = 'Minimum Modulus';
            app.MinimumModulusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinimumModulusEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumModulusEditFieldValueChanged, true);
            app.MaximumModulusEditFieldLabel = uilabel(app.GridLayout2);
            app.MaximumModulusEditFieldLabel.Text = 'Maximum Modulus';
            app.MaximumModulusEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaximumModulusEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumModulusEditFieldValueChanged, true);
            app.MaximumModulusEditField.Value = 1000;
            app.ErrorSpacingEditFieldLabel = uilabel(app.GridLayout2);
            app.ErrorSpacingEditFieldLabel.Text = 'Error Spacing';
            app.ErrorSpacingEditField = uieditfield(app.GridLayout2, 'numeric');
            app.ErrorSpacingEditField.ValueChangedFcn = createCallbackFcn(app, @ErrorSpacingEditFieldValueChanged, true);
            app.ErrorSpacingEditField.Value = 10;
            app.ImporteddataTextAreaLabel = uilabel(app.GridLayout2);
            app.ImporteddataTextAreaLabel.Text = 'Imported data';
            app.ImporteddataTextArea = uitextarea(app.GridLayout2);
            app.ImporteddataTextArea.ValueChangedFcn = createCallbackFcn(app, @ImporteddataTextAreaValueChanged, true);
            app.ImporteddataTextArea.Editable = 'off';
            app.MinimumdepthEditFieldLabel = uilabel(app.GridLayout2);
            app.MinimumdepthEditFieldLabel.Text = 'Minimum depth';
            app.MinimumdepthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MinimumdepthEditField.ValueChangedFcn = createCallbackFcn(app, @MinimumdepthEditFieldValueChanged, true);
            app.MaximumdepthEditFieldLabel = uilabel(app.GridLayout2);
            app.MaximumdepthEditFieldLabel.Text = 'Maximum depth';
            app.MaximumdepthEditField = uieditfield(app.GridLayout2, 'numeric');
            app.MaximumdepthEditField.ValueChangedFcn = createCallbackFcn(app, @MaximumdepthEditFieldValueChanged, true);
            app.MaximumdepthEditField.Value = 1000;
            app.IncludeerrorbarsSwitchLabel = uilabel(app.GridLayout2);
            app.IncludeerrorbarsSwitchLabel.Text = 'Include error bars';
            app.IncludeerrorbarsSwitch = uiswitch(app.GridLayout2, 'slider');
            app.IncludeerrorbarsSwitch.Items = {'Offf', 'On'};
            app.IncludeerrorbarsSwitch.ItemsData = [0 1];
            app.IncludeerrorbarsSwitch.ValueChangedFcn = createCallbackFcn(app, @IncludeerrorbarsSwitchValueChanged, true);
            app.IncludeerrorbarsSwitch.Value = 1;
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'HARDNESS vs DEPTH')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.RightPanel);
            title(app.UIAxes2, 'MODULUS vs DEPTH')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes4 = uiaxes(app.RightPanel);
            title(app.UIAxes4, 'P-h curves')
            xlabel(app.UIAxes4, 'Depth [nm]')
            ylabel(app.UIAxes4, 'Load [mN]')
            zlabel(app.UIAxes4, 'Z')
            app.UIAxes4.Visible = 'off';
            app.VIEWPANELLabel = uilabel(app.RightPanel);
            app.VIEWPANELLabel.Text = 'VIEW PANEL';
            app.ErrorBarsDropDownLabel = uilabel(app.RightPanel);
            app.ErrorBarsDropDownLabel.Text = 'Error Bars';
            app.ErrorBarsDropDown = uidropdown(app.RightPanel);
            app.ErrorBarsDropDown.Items = {'Vertical', 'Horizontal', 'Both'};
            app.ErrorBarsDropDown.ValueChangedFcn = createCallbackFcn(app, @ErrorBarsDropDownValueChanged, true);
            app.ErrorBarsDropDown.Value = 'Vertical';
            app.MarkerEdgeColorDropDownLabel = uilabel(app.RightPanel);
            app.MarkerEdgeColorDropDownLabel.Text = 'Marker Edge Color';
            app.MarkerEdgeColorDropDown = uidropdown(app.RightPanel);
            app.MarkerEdgeColorDropDown.Items = {'Red', 'Blue', 'Green', 'Yellow', 'Black'};
            app.MarkerEdgeColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerEdgeColorDropDownValueChanged, true);
            app.MarkerEdgeColorDropDown.Value = 'Red';
            app.LineColorDropDownLabel = uilabel(app.RightPanel);
            app.LineColorDropDownLabel.Text = 'Line Color';
            app.LineColorDropDown = uidropdown(app.RightPanel);
            app.LineColorDropDown.Items = {'Red', 'Blue', 'Green', 'Yellow', 'Black'};
            app.LineColorDropDown.ValueChangedFcn = createCallbackFcn(app, @LineColorDropDownValueChanged, true);
            app.LineColorDropDown.Value = 'Red';
            app.MarkerSizeSliderLabel = uilabel(app.RightPanel);
            app.MarkerSizeSliderLabel.Text = 'Marker Size';
            app.MarkerSizeSlider = uislider(app.RightPanel);
            app.MarkerSizeSlider.Limits = [1 10];
            app.MarkerSizeSlider.MajorTicks = [1 2.5 4 5.5 7 8.5 10];
            app.MarkerSizeSlider.MajorTickLabels = {'1', '2.5', '4', '5.5', '7', '8.5', '10'};
            app.MarkerSizeSlider.ValueChangedFcn = createCallbackFcn(app, @MarkerSizeSliderValueChanged, true);
            app.MarkerSizeSlider.MinorTicks = [1 1.25 1.5 1.75 2 2.25 2.5 2.75 3 3.25 3.5 3.75 4 4.25 4.5 4.75 5 5.25 5.5 5.75 6 6.25 6.5 6.75 7 7.25 7.5 7.75 8 8.25 8.5 8.75 9 9.25 9.5 9.75 10];
            app.MarkerSizeSlider.Value = 3;
            app.LinewidthSliderLabel = uilabel(app.RightPanel);
            app.LinewidthSliderLabel.Text = 'Linewidth';
            app.LinewidthSlider = uislider(app.RightPanel);
            app.LinewidthSlider.Limits = [0.5 10];
            app.LinewidthSlider.MajorTicks = [0.5 2.4 4.3 6.2 8.1 10];
            app.LinewidthSlider.MajorTickLabels = {'0.5', '2.4', '4.3', '6.2', '8.1', '10'};
            app.LinewidthSlider.ValueChangedFcn = createCallbackFcn(app, @LinewidthSliderValueChanged, true);
            app.LinewidthSlider.MinorTicks = [0.5 1.45 2.4 3.35 4.3 5.25 6.2 7.15 8.1 9.05 10];
            app.LinewidthSlider.Value = 0.5;
            app.LineMarkerStyleDropDownLabel = uilabel(app.RightPanel);
            app.LineMarkerStyleDropDownLabel.Text = 'Line/Marker Style';
            app.LineMarkerStyleDropDown = uidropdown(app.RightPanel);
            app.LineMarkerStyleDropDown.Items = {'Solid/Circle', 'Solid/Square', 'Solid/Diamond', 'Dotted/Cirlce', 'Dotted/Square', 'Dotted/Diamond'};
            app.LineMarkerStyleDropDown.ItemsData = {'o-', 's-', 'd-', 'o--', 's--', 'd--'};
            app.LineMarkerStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @LineMarkerStyleDropDownValueChanged, true);
            app.LineMarkerStyleDropDown.Value = 'o-';
            app.MarkerColorDropDownLabel = uilabel(app.RightPanel);
            app.MarkerColorDropDownLabel.Text = 'Marker Color';
            app.MarkerColorDropDown = uidropdown(app.RightPanel);
            app.MarkerColorDropDown.Items = {'Red', 'Blue', 'Green', 'Yellow', 'Black'};
            app.MarkerColorDropDown.ValueChangedFcn = createCallbackFcn(app, @MarkerColorDropDownValueChanged, true);
            app.MarkerColorDropDown.Value = 'Red';
            app.SeeinfullscreenButton_3 = uibutton(app.RightPanel, 'push');
            app.SeeinfullscreenButton_3.ButtonPushedFcn = createCallbackFcn(app, @SeeinfullscreenButton_3Pushed, true);
            app.SeeinfullscreenButton_3.Enable = 'off';
            app.SeeinfullscreenButton_3.Visible = 'off';
            app.SeeinfullscreenButton_3.Text = 'See in fullscreen';
            app.SeeinfullscreenButton_4 = uibutton(app.RightPanel, 'push');
            app.SeeinfullscreenButton_4.ButtonPushedFcn = createCallbackFcn(app, @SeeinfullscreenButton_4Pushed, true);
            app.SeeinfullscreenButton_4.Enable = 'off';
            app.SeeinfullscreenButton_4.Visible = 'off';
            app.SeeinfullscreenButton_4.Text = 'See in fullscreen';
            app.SeeinfullscreenButton_5 = uibutton(app.RightPanel, 'push');
            app.SeeinfullscreenButton_5.ButtonPushedFcn = createCallbackFcn(app, @SeeinfullscreenButton_5Pushed, true);
            app.SeeinfullscreenButton_5.Enable = 'off';
            app.SeeinfullscreenButton_5.Visible = 'off';
            app.SeeinfullscreenButton_5.Text = 'See in fullscreen';
            app.SeeinfullscreenButton_6 = uibutton(app.RightPanel, 'push');
            app.SeeinfullscreenButton_6.ButtonPushedFcn = createCallbackFcn(app, @SeeinfullscreenButton_6Pushed, true);
            app.SeeinfullscreenButton_6.Enable = 'off';
            app.SeeinfullscreenButton_6.Visible = 'off';
            app.SeeinfullscreenButton_6.Text = 'See in fullscreen';
            app.SeeinfullscreenButton_7 = uibutton(app.RightPanel, 'push');
            app.SeeinfullscreenButton_7.ButtonPushedFcn = createCallbackFcn(app, @SeeinfullscreenButton_7Pushed, true);
            app.SeeinfullscreenButton_7.Enable = 'off';
            app.SeeinfullscreenButton_7.Visible = 'off';
            app.SeeinfullscreenButton_7.Text = 'See in fullscreen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = analyst_app_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end