classdef contourplotapp_femto < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        GridLayout4                   matlab.ui.container.GridLayout
        DepthLevelEditField           matlab.ui.control.NumericEditField
        DepthLevelEditFieldLabel      matlab.ui.control.Label
        SheetnameEditField            matlab.ui.control.EditField
        SheetnameEditFieldLabel       matlab.ui.control.Label
        MachineDropDown               matlab.ui.control.DropDown
        MachineDropDownLabel          matlab.ui.control.Label
        ColorbarlimitsLabel           matlab.ui.control.Label
        QualityDropDown               matlab.ui.control.DropDown
        QualityDropDownLabel          matlab.ui.control.Label
        ImporteddataTextArea          matlab.ui.control.TextArea
        ImporteddataTextAreaLabel     matlab.ui.control.Label
        TesttypeDropDown              matlab.ui.control.DropDown
        TesttypeDropDownLabel         matlab.ui.control.Label
        ContourplottypeDropDown       matlab.ui.control.DropDown
        ContourplottypeDropDownLabel  matlab.ui.control.Label
        LevelsSlider                  matlab.ui.control.Slider
        ContourplotslevelsLabel       matlab.ui.control.Label
        MODULUSmaxvalueSpinner        matlab.ui.control.Spinner
        MODULUSmaxvalueSpinnerLabel   matlab.ui.control.Label
        MODULUSminvalueSpinner        matlab.ui.control.Spinner
        MODULUSminvalueSpinnerLabel   matlab.ui.control.Label
        HARDNESSmaxvalueSpinner       matlab.ui.control.Spinner
        HARDNESSmaxvalueSpinnerLabel  matlab.ui.control.Label
        HARDNESSminvalueSpinner       matlab.ui.control.Spinner
        HARDNESSminvalueSpinnerLabel  matlab.ui.control.Label
        SavecleaneddataButton         matlab.ui.control.Button
        SavehistogramsButton          matlab.ui.control.Button
        SavecontourplotsButton        matlab.ui.control.Button
        AnalyzecontourplotsButton     matlab.ui.control.Button
        AnalyzehistogramsButton       matlab.ui.control.Button
        francescbarberaupceduLabel    matlab.ui.control.Label
        FrancescBarberFlichiLabel     matlab.ui.control.Label
        ResetvaluesButton             matlab.ui.control.Button
        PreviewcontourplotsButton     matlab.ui.control.Button
        PreviewhistogramsButton       matlab.ui.control.Button
        ImportandcleandataButton      matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        GridLayout3                   matlab.ui.container.GridLayout
        UIAxes4                       matlab.ui.control.UIAxes
        UIAxes3                       matlab.ui.control.UIAxes
        UIAxes2                       matlab.ui.control.UIAxes
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        cleandata table 
        hardness_histogram
        modulus_histogram
        contour_hardness
        contour_modulus
        X_sorted
        Y_sorted
        Z_sorted
        Z1_matrix_clipped
        Z2_matrix_clipped
        selected_file
        fig1 % hardness histogram
        fig2 % modulus histogram
        fig3 % hardness contour plot
        fig4 % modulus contour plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ImportandcleandataButton
        function ImportandcleandataButtonPushed(app, event)
            if app.MachineDropDown.Value == "iMicro" 
                app.TesttypeDropDown.Enable = "on";                  
                app.SheetnameEditField.Enable = "on";
                app.TesttypeDropDown.Enable = "on";

                sheet_name = app.SheetnameEditField.Value;
                
                if isempty(sheet_name)
                    uialert(app.UIFigure, 'Sheet name field is empty', 'Error', 'Icon','error')
                    return;
                end
               
                [app.selected_file, path] = uigetfile('*.xlsx;*.xls', 'Select an Excel file');
                
                if isequal(app.selected_file, 0)               
                    return;
                end              
                waitMsg = msgbox('Importing and cleaning data. Please wait...', 'Please Wait', 'modal');
                
                filePath = fullfile(path, app.selected_file);
                excel_file = readtable(filePath, "Sheet", sheet_name, "TextType", "string");
                
                % Verificar si las columnas HARDNESS y MODULUS existen en el archivo Excel
                if ~ismember('HARDNESS', excel_file.Properties.VariableNames) || ~ismember('MODULUS', excel_file.Properties.VariableNames)
                    % Mostrar un mensaje de error si alguna de las columnas no está presente
                    uialert(app.UIFigure, 'HARDNESS and/or MODULUS Excel columns not found. Verify if the file contain both HARDNESS and MODULUS columns, specified in capital letters.', 'Error', 'Icon','error');
                    close(waitMsg);
                    return;
                end
                
                if app.TesttypeDropDown.Value == "3D"
                    app.DepthLevelEditField.Enable = "off";
                    % Excel cleaning
                    rowswithNaN=isnan(excel_file.HARDNESS) | isnan(excel_file.MODULUS);
                    app.cleandata=excel_file(~rowswithNaN,:);
                    app.cleandata(1, :) = [];
                    
                    close(waitMsg);
        
                    app.ImporteddataTextArea.Value = app.selected_file; 
                
                elseif app.TesttypeDropDown.Value == "4D"
                    app.DepthLevelEditField.Enable = "on";
                    % Excel cleaning
                    excel_file = excel_file(~isnan(excel_file.INDENT), :);
                    rowswithNaN=isnan(excel_file.HARDNESS) | isnan(excel_file.MODULUS);
                    app.cleandata=excel_file(~rowswithNaN,:);
                    app.cleandata(1, :) = [];
                    
                    close(waitMsg);
        
                    app.ImporteddataTextArea.Value = app.selected_file; 
                end
                
                % Muestra un mensaje de confirmación al usuario
                uialert(app.UIFigure ,'Data imported sucessfully', 'Success', 'Icon','success');
            end
    
            if app.MachineDropDown.Value == "FemtoTools"
                app.DepthLevelEditField.Enable = "off";
                app.SheetnameEditField.Enable = "off";
                app.TesttypeDropDown.Enable = "off";
                
                [app.selected_file, path] = uigetfile('*.txt', 'Select a Text file');
                                
                if isequal(app.selected_file, 0)               
                    return;
                end
                
                filePath = fullfile(path, app.selected_file);
                opts = detectImportOptions(filePath, 'Delimiter', '\t');
                data = readtable(filePath, opts);
                
                waitMsg = msgbox('Importing and cleaning data. Please wait...', 'Please Wait', 'modal');
                     
                % Verificar si las columnas HARDNESS y MODULUS existen en el archivo Excel
                if ~ismember('Hardness_MPa_', data.Properties.VariableNames) || ~ismember('ReducedMod__MPa_', data.Properties.VariableNames)
                    % Mostrar un mensaje de error si alguna de las columnas no está presente
                    uialert(app.UIFigure, 'HARDNESS and/or MODULUS Excel columns not found. Verify if the file contain both HARDNESS and MODULUS columns, specified in capital letters.', 'Error', 'Icon','error');
                    close(waitMsg);
                    return;
                end
    
                
                % Acondicionamiento: establecer posición 0, cambio unidades MPa a GPa
                num_filas = size(data, 1);
                
                data.PosZ = zeros(num_filas, 1);
                data.PosY= zeros(num_filas, 1);
                data.Hardness_GPa = zeros(num_filas, 1);
                data.ReducedMod_GPa = zeros(num_filas, 1);

                for i = 1:num_filas
                    % Establecer el 0
                    data.PosZ(i) = round(data.PosZ_um_(i) - data.PosZ_um_(1),0); 
                    data.PosY(i) = round(data.PosY_um_(i) - data.PosY_um_(1),0);
                    % MPa a GPa
                    data.Hardness_GPa(i) = data.Hardness_MPa_(i).*10^-3;
                    data.ReducedMod_GPa(i) = data.ReducedMod__MPa_(i).*10^-3;
                end
                
                rowswithNaN=isnan(data.Hardness_MPa_) | isnan(data.ReducedMod__MPa_);
                app.cleandata=data(~rowswithNaN,:);
                app.cleandata(1, :) = [];
                
                close(waitMsg);
    
                app.ImporteddataTextArea.Value = app.selected_file; 

                uialert(app.UIFigure ,'Data cleaned and imported sucessfully', 'Success', 'Icon','success');
            end

        end

        % Button pushed function: SavecleaneddataButton
        function SavecleaneddataButtonPushed(app, event)
            if isempty(app.selected_file)
                uialert(app.UIFigure, 'File selection empty.', 'Error','Icon','error');
                return;
            end
            
            % Obtener el nombre del archivo de destino del usuario
            [savefile, savepath] = uiputfile('*.xlsx', 'Save Cleaned Data as Excel');
            
            % Verificar si el usuario ha cancelado la operación de guardado
            if isequal(savefile, 0)
                return;
            end
            
            % Crear la ruta completa para el nuevo archivo Excel
            saveFilePath = fullfile(savepath, savefile);
            
            % Guardar los datos limpios en el nuevo archivo Excel
            writetable(app.cleandata, saveFilePath, 'Sheet', app.SheetnameEditField.Value);
            
            % Muestra un mensaje de confirmación al usuario
            uialert(app.UIFigure, ['Cleaned data has been saved in ' saveFilePath], 'Success', 'Icon','success');

        end

        % Button pushed function: PreviewhistogramsButton
        function PreviewhistogramsButtonPushed(app, event)
            if app.MachineDropDown.Value == "iMicro"  
                if ~isempty(app.cleandata)
                    app.hardness_histogram = histogram(app.UIAxes,app.cleandata.HARDNESS);
                    app.modulus_histogram = histogram(app.UIAxes2,app.cleandata.MODULUS);
                    xlabel(app.UIAxes, 'Hardness [GPa]');
                    ylabel(app.UIAxes, 'Frequency');
                    xlabel(app.UIAxes2, 'Reduced Modulus [GPa]');
                    ylabel(app.UIAxes2, 'Frequency');
                else
                    uialert(app.UIFigure, 'No data imported.', 'Error', 'Icon', 'error');
                end

            elseif app.MachineDropDown.Value == "FemtoTools"
                if ~isempty(app.cleandata)
                    app.hardness_histogram = histogram(app.UIAxes,app.cleandata.Hardness_GPa);
                    app.modulus_histogram = histogram(app.UIAxes2,app.cleandata.ReducedMod_GPa);
                    xlabel(app.UIAxes, 'Hardness [GPa]');
                    ylabel(app.UIAxes, 'Frequency');
                    xlabel(app.UIAxes2, 'Reduced Modulus [GPa]');
                    ylabel(app.UIAxes2, 'Frequency');
                else
                    uialert(app.UIFigure, 'No data imported.', 'Error', 'Icon', 'error');
                end
            end

        end

        % Value changed function: HARDNESSminvalueSpinner
        function HARDNESSminvalueSpinnerValueChanged(app, event)
            hmin_value = app.HARDNESSminvalueSpinner.Value;
            
        end

        % Value changed function: HARDNESSmaxvalueSpinner
        function HARDNESSmaxvalueSpinnerValueChanged(app, event)
            hmax_value = app.HARDNESSmaxvalueSpinner.Value;
            
        end

        % Value changed function: MODULUSminvalueSpinner
        function MODULUSminvalueSpinnerValueChanged(app, event)
            mmin_value = app.MODULUSminvalueSpinner.Value;
            
        end

        % Value changed function: MODULUSmaxvalueSpinner
        function MODULUSmaxvalueSpinnerValueChanged(app, event)
            mmax_value = app.MODULUSmaxvalueSpinner.Value;
            
        end

        % Button pushed function: PreviewcontourplotsButton
        function PreviewcontourplotsButtonPushed(app, event)
             
            if isempty(app.cleandata)
                uialert(app.UIFigure, 'No data imported', 'Error', 'Icon', 'error');
                return;
            end
            
                % Definir las variables hmin_value y hmax_value
                hmin_value = app.HARDNESSminvalueSpinner.Value;
                hmax_value = app.HARDNESSmaxvalueSpinner.Value;
        
                mmin_value = app.MODULUSminvalueSpinner.Value;
                mmax_value = app.MODULUSmaxvalueSpinner.Value;
                
                % Verificar que los límites son válidos
            if hmin_value >= hmax_value || mmin_value >= mmax_value
                % Mostrar un mensaje de error al usuario
                uialert(app.UIFigure, 'The color scale limits are invalid. The lower limit must be less than the upper limit.', 'Error', 'Icon', 'error');
                return;               
            end

            if app.MachineDropDown.Value == "iMicro" 
                % DATA FOR THE CONTOUR PLOTS
                try
                    if app.TesttypeDropDown.Value == "3D"
                        % 3D MAPPING
                        app.X_sorted = sort(unique(app.cleandata.XPosition));
                        app.Y_sorted = sort(unique(app.cleandata.YPosition));
                
                        [x_grid, y_grid] = meshgrid(app.X_sorted, app.Y_sorted);
                
                        % 3D MAPPING
                        Z1_interpolated = griddata(app.cleandata.XPosition, app.cleandata.YPosition, app.cleandata.HARDNESS, x_grid, y_grid, 'linear');
                        Z2_interpolated = griddata(app.cleandata.XPosition, app.cleandata.YPosition, app.cleandata.MODULUS, x_grid, y_grid, 'linear');
                
                
                    % elseif app.TesttypeDropDown.Value == "4D"
                    %     % 4D MAPPING
                    %     app.X_sorted = sort(unique(app.cleandata.X));
                    %     app.Y_sorted = sort(unique(app.cleandata.Y));
                    % 
                    %     [x_grid, y_grid] = meshgrid(app.X_sorted, app.Y_sorted);
                    % 
                    %     % 4D MAPPING
                    %     Z1_interpolated = griddata(app.cleandata.X, app.cleandata.Y, app.cleandata.HARDNESS, x_grid, y_grid, 'linear');
                    %     Z2_interpolated = griddata(app.cleandata.X, app.cleandata.Y, app.cleandata.MODULUS, x_grid, y_grid, 'linear');
                    % 
                    % end

                   elseif app.TesttypeDropDown.Value == "4D"
                       app.DepthLevelEditField.Enable = "on";   
                       depthlevel = app.DepthLevelEditField.Value;
                                                    
                        % Obtener los grupos únicos de INDENT
                        uniqueIndents = unique(app.cleandata.INDENT);
                        app.cleandata = [];
                    
                        for i = 1:length(uniqueIndents)
                            groupData = app.cleandata(app.cleandata.INDENT == uniqueIndents(i), :);
                            if height(groupData) >= depthlevel
                                app.cleandata = [app.cleandata; groupData(depthlevel, :)];
                            else
                                % Si no hay suficientes filas en el grupo, tomar la última disponible
                                app.cleandata = [app.cleandata; groupData(end, :)];
                            end
                        end
                        
                        app.X_sorted = sort(unique(app.cleandata.X));
                        app.Y_sorted = sort(unique(app.cleandata.Y));

                        [x_grid, y_grid] = meshgrid(app.X_sorted, app.Y_sorted);

                        %4D MAPPING
                        Z1_interpolated = griddata(app.cleandata.X, app.cleandata.Y, app.cleandata.HARDNESS, x_grid, y_grid, 'linear');
                        Z2_interpolated = griddata(app.cleandata.X, app.cleandata.Y, app.cleandata.MODULUS, x_grid, y_grid, 'linear');

                    end

                catch exception
                    errordlg(['Error: ' exception.message 'Check the test type selection'], 'Variable Error');
                end
            elseif app.MachineDropDown.Value == "FemtoTools"
                 app.Y_sorted = sort(unique(round(app.cleandata.PosY,0)));
                 app.Z_sorted = sort(unique(round(app.cleandata.PosZ,0)));
                
                 [z_grid, y_grid] = meshgrid(app.Z_sorted, app.Y_sorted);
                
                % 3D MAPPING
                Z1_interpolated = griddata(app.cleandata.PosY, app.cleandata.PosZ, app.cleandata.Hardness_GPa, z_grid, y_grid, 'linear');
                Z2_interpolated = griddata(app.cleandata.PosY, app.cleandata.PosZ, app.cleandata.ReducedMod_GPa, z_grid, y_grid, 'linear');
            
            end
            
            % CREATION OF THE CONTOUR PLOTS
            % HARDNESS contour plot
            % Recortar los valores interpolados según vmin/vmax
            app.Z1_matrix_clipped = min(max(Z1_interpolated, hmin_value), hmax_value);

            if app.MachineDropDown.Value == "iMicro" 
            % Crear el gráfico de contorno lleno (contourf)
                app.contour_hardness = contourf(app.UIAxes3, app.X_sorted, app.Y_sorted, app.Z1_matrix_clipped, app.LevelsSlider.Value, 'LineStyle', 'none');
            elseif app.MachineDropDown.Value == "FemtoTools"
                app.contour_hardness = contourf(app.UIAxes3, app.Y_sorted, app.Z_sorted, app.Z1_matrix_clipped, app.LevelsSlider.Value, 'LineStyle', 'none');
            end

            % Colocar la barra de color
            cbar1 = colorbar(app.UIAxes3);
            cbar1.Label.String = 'Hardness [GPa]';
            cbar1.Label.FontSize = 18;         

            % Modificar los límites de la colorbar
            clim(app.UIAxes3, [hmin_value, hmax_value]); % Utiliza caxis para establecer los límites

            colormap(app.UIAxes3, app.ContourplottypeDropDown.Value);

            % Configurar el tamaño de fuente para los ejes y la barra de color
            ax3 = app.UIAxes3;
            ax3.FontSize = 18;
            cbar1.FontSize = 18;

            % Configurar las etiquetas de los ejes
            xlabel(ax3,'Y Position [μm]', 'FontSize', 18);
            ylabel(ax3,'Z Position [μm]', 'FontSize', 18);
           
            % Ajustar el tamaño de fuente de los títulos de los ejes X e Y
            ax3.XLabel.FontSize = 18;
            ax3.YLabel.FontSize = 18;
            
            xticks(ax3, 'auto');
            yticks(ax3, 'auto');
            ax3.XAxis.TickDirection = 'out';
            ax3.YAxis.TickDirection = 'out';
            ax3.TickLength = [0.01, 0.01];
            % Establecer el título del subplot
            title(ax3,'HARDNESS contour plot', 'FontSize', 18);

            axis(ax3, 'equal');

            % MODULUS contour plot
            % Recortar los valores interpolados según vmin/vmax
            app.Z2_matrix_clipped = min(max(Z2_interpolated, mmin_value), mmax_value);

            if app.MachineDropDown.Value == "iMicro" 
            % Crear el gráfico de contorno lleno (contourf)
                app.contour_modulus = contourf(app.UIAxes4, app.X_sorted, app.Y_sorted, app.Z2_matrix_clipped, app.LevelsSlider.Value, 'LineStyle', 'none');
            elseif app.MachineDropDown.Value == "FemtoTools"
                app.contour_modulus = contourf(app.UIAxes4, app.Y_sorted, app.Z_sorted, app.Z2_matrix_clipped, app.LevelsSlider.Value, 'LineStyle', 'none');
            end

            % Colocar la barra de color
            cbar2 = colorbar(app.UIAxes4);
            cbar2.Label.String = 'Modulus [GPa]';
            cbar2.Label.FontSize = 18;

            % Modificar los límites de la colorbar
            clim(app.UIAxes4, [mmin_value, mmax_value]); % Utiliza caxis para establecer los límites

            colormap(app.UIAxes4, app.ContourplottypeDropDown.Value);

            % Configurar el tamaño de fuente para los ejes y la barra de color
            ax4 = app.UIAxes4;
            ax4.FontSize = 18;
            cbar2.FontSize = 18;

            % Configurar las etiquetas de los ejes
            xlabel(ax4,'Y Position [μm]', 'FontSize', 18);
            ylabel(ax4,'Z Position [μm]', 'FontSize', 18);

            % Ajustar el tamaño de fuente de los títulos de los ejes X e Y
            ax4.XLabel.FontSize = 18;
            ax4.YLabel.FontSize = 18;
            
            xticks(ax4, 'auto');
            yticks(ax4, 'auto');
            ax4.XAxis.TickDirection = 'out';
            ax4.YAxis.TickDirection = 'out';
            ax4.TickLength = [0.01, 0.01];

            % Establecer el título del subplot
            title(ax4,'MODULUS contour plot', 'FontSize', 18);

            axis(ax4, 'equal');          
        
           
        end

        % Button pushed function: ResetvaluesButton
        function ResetvaluesButtonPushed(app, event)
            app.HARDNESSminvalueSpinner.Value = 0;
            app.HARDNESSmaxvalueSpinner.Value = 0;
            app.MODULUSminvalueSpinner.Value = 0; 
            app.MODULUSmaxvalueSpinner.Value = 0; 
        end

        % Button pushed function: AnalyzehistogramsButton
        function AnalyzehistogramsButtonPushed(app, event)
            % Verificar si existen histogramas para guardar
            if isempty(app.hardness_histogram) || isempty(app.modulus_histogram)
                uialert(app.UIFigure, 'No histograms to show.', 'Error', 'Icon', 'error');
                return;
            end             

            % Crear una nueva figura
            app.fig1 = figure;

            % Agregar un conjunto de ejes a la figura
            ax1 = axes(app.fig1);

            % Asumiendo que app.hardness es un gráfico válido, puedes copiarlo a los nuevos ejes
            copyobj(app.hardness_histogram, ax1);

            % Personalizar los ejes o la figura según sea necesario
            xlabel(ax1, 'Hardness [GPa]');
            ylabel(ax1, 'Frequency');
            title(ax1, 'Hardness Histogram');  

            app.fig2 = figure;
            ax2 = axes(app.fig2);
            copyobj(app.modulus_histogram, ax2);
            xlabel(ax2, 'Modulus [GPa]');
            ylabel(ax2, 'Frequency');
            title(ax2, 'Modulus Historgam');
            

        end

        % Button pushed function: SavehistogramsButton
        function SavehistogramsButtonPushed(app, event)
            if isempty(app.hardness_histogram) || isempty(app.modulus_histogram)
                uialert(app.UIFigure, 'No histograms to save.', 'Error', 'Icon', 'error');
                return;
            end     
            
            % Obtener el nombre del archivo de destino del usuario
            [filename, path] = uiputfile('*.png', 'Save histograms as PNG');
    
            % Verificar si el usuario ha cancelado la operación de guardado
            if isequal(filename, 0)
                return;
            end
              
            h = waitbar(0, 'Saving...');  
            
            % Verificar si el nombre del archivo ya tiene la extensión .png
            [~, name, ext] = fileparts(filename);
            if strcmpi(ext, '.png')
                % Eliminar la extensión .png si ya está presente
                filename = name;
            end
            
            % Crear una ruta completa para los archivos de destino
            fullpath1 = fullfile(path, [filename, '_HARDNESS.png']);
            fullpath2 = fullfile(path, [filename, '_MODULUS.png']);

            exportgraphics(app.UIAxes, fullpath1, 'Resolution', app.QualityDropDown.Value, 'BackgroundColor', 'none');
            waitbar(0.5, h, 'Saving...');

            exportgraphics(app.UIAxes2, fullpath2, 'Resolution', app.QualityDropDown.Value, 'BackgroundColor', 'none');
            waitbar(1, h, 'Saving...');

            close(h);

            % Muestra un mensaje de confirmación al usuario
            uialert(app.UIFigure, ['Histograms have been saved in' fullpath1 ' and ' fullpath2], 'Success', 'Icon','success');

        end

        % Button pushed function: AnalyzecontourplotsButton
        function AnalyzecontourplotsButtonPushed(app, event)
              if isempty(app.contour_hardness) || isempty(app.contour_modulus)
                  uialert(app.UIFigure, 'No contour plots to show.', 'Error', 'Icon', 'error');
                  return;
              end

             if isempty(app.contour_hardness) || isempty(app.contour_modulus)
                  uialert(app.UIFigure, 'No contour plots to show.', 'Error', 'Icon', 'error');
                  return;
              end

            % Definir las variables hmin_value y hmax_value
            hmin_value = app.HARDNESSminvalueSpinner.Value;
            hmax_value = app.HARDNESSmaxvalueSpinner.Value;
            mmin_value = app.MODULUSminvalueSpinner.Value;
            mmax_value = app.MODULUSmaxvalueSpinner.Value;
           
            % Crear una nueva figura para el gráfico de contorno de HARDNESS
            app.fig3 = figure;
            ax3 = axes(app.fig3);

            if app.MachineDropDown.Value == "iMicro" 
                contourf(ax3, app.X_sorted, app.Y_sorted, app.Z1_matrix_clipped, app.LevelsSlider.Value, 'LineStyle','none');            
                colormap(ax3, app.ContourplottypeDropDown.Value);
            elseif app.MachineDropDown.Value == "FemtoTools"
                contourf(ax3, app.Y_sorted, app.Z_sorted, app.Z1_matrix_clipped, app.LevelsSlider.Value, 'LineStyle','none');            
                colormap(ax3, app.ContourplottypeDropDown.Value);
            end
    
            % Configurar etiquetas y título            
            title(ax3, 'HARDNESS contour plot', 'FontSize', 18);
            colorbar(ax3, 'FontSize', 18);
            colormap(app.fig3, app.ContourplottypeDropDown.Value);
            clim(ax3, [hmin_value, hmax_value]);
            axis(ax3, 'equal');
            xticks(ax3, 'auto');
            yticks(ax3, 'auto');
            ax3.XAxis.TickDirection = 'out';
            ax3.YAxis.TickDirection = 'out';
            ax3.TickLength = [0.01, 0.01];
            if app.MachineDropDown.Value == "iMicro"
                xlabel(ax3, 'X Position [μm]', 'FontSize', 18);
                ylabel(ax3, 'Y Position [μm]', 'FontSize', 18);
            elseif app.MachineDropDown.Value == "FemtoTools"
                xlabel(ax3, 'Y Position [μm]', 'FontSize', 18);
                ylabel(ax3, 'Z Position [μm]', 'FontSize', 18);
            end

            % Crear una nueva figura para el gráfico de contorno de MODULUS
            app.fig4 = figure;
            ax4 = axes(app.fig4);
            if app.MachineDropDown.Value == "iMicro" 
                contourf(ax4, app.X_sorted, app.Y_sorted, app.Z2_matrix_clipped, app.LevelsSlider.Value, 'LineStyle','none');
                colormap(ax4, app.ContourplottypeDropDown.Value);
            elseif app.MachineDropDown.Value == "FemtoTools"
                contourf(ax4, app.Y_sorted, app.Z_sorted, app.Z2_matrix_clipped, app.LevelsSlider.Value, 'LineStyle','none');            
                colormap(ax4, app.ContourplottypeDropDown.Value);
            end

            % Configurar etiquetas y título
            title(ax4, 'MODULUS contour plot', 'FontSize', 18);
            colorbar(ax4, 'FontSize', 18);
            clim(ax4, [mmin_value, mmax_value]);
            colormap(app.fig4, app.ContourplottypeDropDown.Value);
            axis(ax4, 'equal');
            xticks(ax4, 'auto');
            yticks(ax4, 'auto');
            ax4.XAxis.TickDirection = 'out';
            ax4.YAxis.TickDirection = 'out';
            ax4.TickLength = [0.01, 0.01];
            if app.MachineDropDown.Value == "iMicro"
                xlabel(ax4, 'X Position [μm]', 'FontSize', 18);
                ylabel(ax4, 'Y Position [μm]', 'FontSize', 18);
            elseif app.MachineDropDown.Value == "FemtoTools"
                xlabel(ax4, 'Y Position [μm]', 'FontSize', 18);
                ylabel(ax4, 'Z Position [μm]', 'FontSize', 18);
            end

        end

        % Button pushed function: SavecontourplotsButton
        function SavecontourplotsButtonPushed(app, event)
            if isempty(app.hardness_histogram) || isempty(app.modulus_histogram)
                uialert(app.UIFigure, 'No contour plots to save.', 'Error', 'Icon', 'error');
                return;
            end     
            
            % Obtener el nombre del archivo de destino del usuario
            [filename, path] = uiputfile('*.png', 'Save Contour Plots as PNG');   
            
            h = waitbar(0, 'Saving...');

            % Verificar si el usuario ha cancelado la operación de guardado
            if isequal(filename, 0)
                close(h);
                return;              
            end                     
             
                        
            % Verificar si el nombre del archivo ya tiene la extensión .png
            [~, name, ext] = fileparts(filename);
            if strcmpi(ext, '.png')
                % Eliminar la extensión .png si ya está presente
                filename = name;
            end
            
            % Crear una ruta completa para los archivos de destino
            fullpath1 = fullfile(path, [filename, '_HARDNESS.png']);
            fullpath2 = fullfile(path, [filename, '_MODULUS.png']);

            % Guardar las figuras como archivos PNG
            exportgraphics(app.UIAxes3, fullpath1, 'Resolution', app.QualityDropDown.Value, 'BackgroundColor', 'none');
            waitbar(0.5, h, 'Saving...');
            
            exportgraphics(app.UIAxes4, fullpath2, 'Resolution', app.QualityDropDown.Value, 'BackgroundColor', 'none');
            waitbar(1, h, 'Saving...');

            close(h);
    
            % Muestra un mensaje de confirmación al usuario
            uialert(app.UIFigure, ['Contour plots have been saved in ' fullpath1 ' and ' fullpath2], 'Success', 'Icon','success');
        end

        % Value changed function: MachineDropDown
        function MachineDropDownValueChanged(app, event)
            value = app.MachineDropDown.Value;

        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {1080, 1080};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {290, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.LeftPanel);
            app.ImportandcleandataButton = uibutton(app.GridLayout4, 'push');
            app.ImportandcleandataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportandcleandataButtonPushed, true);
            app.ImportandcleandataButton.Text = 'Import and clean data';
            app.PreviewhistogramsButton = uibutton(app.GridLayout4, 'push');
            app.PreviewhistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewhistogramsButtonPushed, true);
            app.PreviewhistogramsButton.Text = 'Preview histograms';
            app.PreviewcontourplotsButton = uibutton(app.GridLayout4, 'push');
            app.PreviewcontourplotsButton.ButtonPushedFcn = createCallbackFcn(app, @PreviewcontourplotsButtonPushed, true);
            app.PreviewcontourplotsButton.Text = 'Preview contour plots';
            app.ResetvaluesButton = uibutton(app.GridLayout4, 'push');
            app.ResetvaluesButton.ButtonPushedFcn = createCallbackFcn(app, @ResetvaluesButtonPushed, true);
            app.ResetvaluesButton.Text = 'Reset values';
            app.FrancescBarberFlichiLabel = uilabel(app.GridLayout4);
            app.FrancescBarberFlichiLabel.Text = 'Francesc Barberá Flichi';
            app.francescbarberaupceduLabel = uilabel(app.GridLayout4);
            app.francescbarberaupceduLabel.Text = 'francesc.barbera@upc.edu';
            app.AnalyzehistogramsButton = uibutton(app.GridLayout4, 'push');
            app.AnalyzehistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzehistogramsButtonPushed, true);
            app.AnalyzehistogramsButton.Text = 'Analyze histograms ';
            app.AnalyzecontourplotsButton = uibutton(app.GridLayout4, 'push');
            app.AnalyzecontourplotsButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzecontourplotsButtonPushed, true);
            app.AnalyzecontourplotsButton.Text = 'Analyze contour plots';
            app.SavecontourplotsButton = uibutton(app.GridLayout4, 'push');
            app.SavecontourplotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavecontourplotsButtonPushed, true);
            app.SavecontourplotsButton.Text = 'Save contour plots';
            app.SavehistogramsButton = uibutton(app.GridLayout4, 'push');
            app.SavehistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @SavehistogramsButtonPushed, true);
            app.SavehistogramsButton.Text = 'Save histograms';
            app.SavecleaneddataButton = uibutton(app.GridLayout4, 'push');
            app.SavecleaneddataButton.ButtonPushedFcn = createCallbackFcn(app, @SavecleaneddataButtonPushed, true);
            app.SavecleaneddataButton.Text = 'Save cleaned data';
            app.HARDNESSminvalueSpinnerLabel = uilabel(app.GridLayout4);
            app.HARDNESSminvalueSpinnerLabel.Text = 'HARDNESS min. value';
            app.HARDNESSminvalueSpinner = uispinner(app.GridLayout4);
            app.HARDNESSminvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @HARDNESSminvalueSpinnerValueChanged, true);
            app.HARDNESSmaxvalueSpinnerLabel = uilabel(app.GridLayout4);
            app.HARDNESSmaxvalueSpinnerLabel.Text = 'HARDNESS max. value';
            app.HARDNESSmaxvalueSpinner = uispinner(app.GridLayout4);
            app.HARDNESSmaxvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @HARDNESSmaxvalueSpinnerValueChanged, true);
            app.MODULUSminvalueSpinnerLabel = uilabel(app.GridLayout4);
            app.MODULUSminvalueSpinnerLabel.Text = 'MODULUS min. value';
            app.MODULUSminvalueSpinner = uispinner(app.GridLayout4);
            app.MODULUSminvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @MODULUSminvalueSpinnerValueChanged, true);
            app.MODULUSmaxvalueSpinnerLabel = uilabel(app.GridLayout4);
            app.MODULUSmaxvalueSpinnerLabel.Text = 'MODULUS max. value';
            app.MODULUSmaxvalueSpinner = uispinner(app.GridLayout4);
            app.MODULUSmaxvalueSpinner.ValueChangedFcn = createCallbackFcn(app, @MODULUSmaxvalueSpinnerValueChanged, true);
            app.ContourplotslevelsLabel = uilabel(app.GridLayout4);
            app.ContourplotslevelsLabel.Text = 'Levels';
            app.LevelsSlider = uislider(app.GridLayout4);
            app.LevelsSlider.Limits = [1 200];
            app.LevelsSlider.MajorTicks = [1 100 200];
            app.LevelsSlider.Value = 1;
            app.ContourplottypeDropDownLabel = uilabel(app.GridLayout4);
            app.ContourplottypeDropDownLabel.Text = 'Contour plot type';
            app.ContourplottypeDropDown = uidropdown(app.GridLayout4);
            app.ContourplottypeDropDown.Items = {'Parula', 'Jet', 'Hot', 'Cool', 'Spring', 'Summer', 'Winter', 'Gray'};
            app.ContourplottypeDropDown.Value = 'Jet';
            app.TesttypeDropDownLabel = uilabel(app.GridLayout4);
            app.TesttypeDropDownLabel.Text = 'Test type';
            app.TesttypeDropDown = uidropdown(app.GridLayout4);
            app.TesttypeDropDown.Items = {'3D', '4D'};
            app.TesttypeDropDown.Value = '3D';
            app.ImporteddataTextAreaLabel = uilabel(app.GridLayout4);
            app.ImporteddataTextAreaLabel.Text = 'Imported data';
            app.ImporteddataTextArea = uitextarea(app.GridLayout4);
            app.ImporteddataTextArea.Editable = 'off';
            app.QualityDropDownLabel = uilabel(app.GridLayout4);
            app.QualityDropDownLabel.Text = 'Quality';
            app.QualityDropDown = uidropdown(app.GridLayout4);
            app.QualityDropDown.Items = {'Low', 'High', 'Best'};
            app.QualityDropDown.ItemsData = {'200', '500', '700'};
            app.QualityDropDown.Value = '500';
            app.ColorbarlimitsLabel = uilabel(app.GridLayout4);
            app.ColorbarlimitsLabel.Text = 'Color bar limits';
            app.MachineDropDownLabel = uilabel(app.GridLayout4);
            app.MachineDropDownLabel.Text = 'Machine';
            app.MachineDropDown = uidropdown(app.GridLayout4);
            app.MachineDropDown.Items = {'iMicro', 'FemtoTools'};
            app.MachineDropDown.ValueChangedFcn = createCallbackFcn(app, @MachineDropDownValueChanged, true);
            app.MachineDropDown.Value = 'iMicro';
            app.SheetnameEditFieldLabel = uilabel(app.GridLayout4);
            app.SheetnameEditFieldLabel.Text = 'Sheet name';
            app.SheetnameEditField = uieditfield(app.GridLayout4, 'text');
            app.SheetnameEditField.Value = 'Test 1';
            app.DepthLevelEditFieldLabel = uilabel(app.GridLayout4);
            app.DepthLevelEditFieldLabel.Text = 'Depth Level';
            app.DepthLevelEditField = uieditfield(app.GridLayout4, 'numeric');
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, 'HARDNESS histogram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.GridLayout3);
            title(app.UIAxes2, 'MODULUS histogram')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.GridLayout3);
            title(app.UIAxes3, 'HARDNESS contour plot')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.GridLayout3);
            title(app.UIAxes4, 'MODULUS contour plot')
            xlabel(app.UIAxes4, 'X')
            ylabel(app.UIAxes4, 'Y')
            zlabel(app.UIAxes4, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = contourplotapp_femto

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end